classdef caiman_sorter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        findsimilarcompButton           matlab.ui.control.Button
        CellselectionPanel              matlab.ui.container.Panel
        CellcategoryButtonGroup         matlab.ui.container.ButtonGroup
        AllButton                       matlab.ui.control.ToggleButton
        RejectedButton                  matlab.ui.control.ToggleButton
        AcceptedButton                  matlab.ui.control.ToggleButton
        CellnumberSpinner               matlab.ui.control.Spinner
        CellnumberSpinnerLabel          matlab.ui.control.Label
        PressupdownkeytochangecellnumButton  matlab.ui.control.StateButton
        TabGroup2                       matlab.ui.container.TabGroup
        SpatialcomponentsTab            matlab.ui.container.Tab
        UIAxes_Rejected                 matlab.ui.control.UIAxes
        UIAxes_Accepted                 matlab.ui.control.UIAxes
        DeconvolutionTab                matlab.ui.container.Tab
        UpdateplotsButton               matlab.ui.control.Button
        PlotMCMCSwitch                  matlab.ui.control.Switch
        PlotMCMCSwitchLabel             matlab.ui.control.Label
        PlotconstfoopsiSwitch           matlab.ui.control.Switch
        PlotconstfoopsiSwitchLabel      matlab.ui.control.Label
        PlotsmoothdfofSwitch            matlab.ui.control.Switch
        PlotsmoothdfofSwitchLabel       matlab.ui.control.Label
        OverwriteCheckBox               matlab.ui.control.CheckBox
        RunallcellsButton               matlab.ui.control.Button
        RunacceptedcellsButton          matlab.ui.control.Button
        RuncurrentcellButton            matlab.ui.control.Button
        DataframeratefpsEditField       matlab.ui.control.NumericEditField
        DataframeratefpsEditFieldLabel  matlab.ui.control.Label
        TauriseEditFieldAR2             matlab.ui.control.NumericEditField
        TaurisesecLabel_3               matlab.ui.control.Label
        TaudecayEditFieldAR2            matlab.ui.control.NumericEditField
        TaudecaysecLabel_3              matlab.ui.control.Label
        Estimatedtimeconstantsar2Label  matlab.ui.control.Label
        TaudecayEditFieldAR1            matlab.ui.control.NumericEditField
        TaudecaysecLabel_4              matlab.ui.control.Label
        Estimatedtimeconstantar1Label   matlab.ui.control.Label
        CellEditField                   matlab.ui.control.NumericEditField
        CellEditFieldLabel              matlab.ui.control.Label
        DeonvolutionTypeGroup           matlab.ui.container.TabGroup
        smoothdfdtTab                   matlab.ui.container.Tab
        ShiftEditField                  matlab.ui.control.NumericEditField
        ShiftEditFieldLabel             matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        PlotthresholdCheckBox           matlab.ui.control.CheckBox
        ThresholdmagZEditField          matlab.ui.control.NumericEditField
        ThresholdmagZEditFieldLabel     matlab.ui.control.Label
        ApplythresholdCheckBox          matlab.ui.control.CheckBox
        PlottingparamsLabel             matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        NormalizeCheckBox               matlab.ui.control.CheckBox
        RectifyCheckBox                 matlab.ui.control.CheckBox
        Label_6                         matlab.ui.control.Label
        PlotkernelButtonSmoothdfdt      matlab.ui.control.Button
        GaussKernelSimgaSmoothdfdt      matlab.ui.control.NumericEditField
        GaussiansigmamsLabel_4          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ConvolvewithgaussiankernelCheckBoxSmoothdfdt  matlab.ui.control.CheckBox
        FirstderivativeLabel            matlab.ui.control.Label
        SignalextractionparamsLabel     matlab.ui.control.Label
        constrainedfoopsiTab            matlab.ui.container.Tab
        DeconvMethodDropDownCfoopsi     matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        FudgeFactorEditField            matlab.ui.control.NumericEditField
        FudgeFactorEditFieldLabel       matlab.ui.control.Label
        ShiftEditFieldCfoopsi           matlab.ui.control.NumericEditField
        ShiftEditField_2Label_3         matlab.ui.control.Label
        ScaleEditFieldCfoopsi           matlab.ui.control.NumericEditField
        ScaleEditField_2Label_3         matlab.ui.control.Label
        PlottingparamsLabelCfoopsi      matlab.ui.control.Label
        PlotkernelButtonCfoopsi         matlab.ui.control.Button
        GaussKernelSimgaCfoopsi         matlab.ui.control.NumericEditField
        GaussiansigmamsLabel_5          matlab.ui.control.Label
        ConvolvewithgaussiankernelCheckBoxCfoopsi  matlab.ui.control.CheckBox
        PostDeconvolutionparamsLabelCfoopsi  matlab.ui.control.Label
        TaudecayEditFieldCfoopsi        matlab.ui.control.NumericEditField
        TaudecaysecLabel                matlab.ui.control.Label
        TauriseEditFieldCfoopsi         matlab.ui.control.NumericEditField
        TaurisesecLabel                 matlab.ui.control.Label
        UsemanualtimeconstantsCheckBoxCfoopsi  matlab.ui.control.CheckBox
        ARmodelSwitchCfoopsi            matlab.ui.control.Switch
        ARmodelSwitch_2Label_3          matlab.ui.control.Label
        DeconvolutionparamsLabelCfoopsi  matlab.ui.control.Label
        MCMCTab                         matlab.ui.container.Tab
        PlotMCMCSAMPLESdetailsButton    matlab.ui.control.Button
        ShiftEditFieldMCMC              matlab.ui.control.NumericEditField
        ShiftEditField_2Label_4         matlab.ui.control.Label
        ScaleEditFieldMCMC              matlab.ui.control.NumericEditField
        ScaleEditField_2Label_4         matlab.ui.control.Label
        PlottingparamsLabelMCMC         matlab.ui.control.Label
        PlotkernelButtonMCMC            matlab.ui.control.Button
        GaussKernelSimgaMCMC            matlab.ui.control.NumericEditField
        GaussiansigmamsLabel_6          matlab.ui.control.Label
        ConvolvewithgaussiankernelCheckBoxMCMC  matlab.ui.control.CheckBox
        PostDeconvolutionparamsLabelMCMC  matlab.ui.control.Label
        SaveSamplesOutputsMCMC          matlab.ui.control.CheckBox
        MCMCNNumberOfSamples            matlab.ui.control.NumericEditField
        NNumberofsamplesafterburnindefault500Label  matlab.ui.control.Label
        MCMCBurnInSamples               matlab.ui.control.NumericEditField
        BNumberofburninsamplesdefault200Label  matlab.ui.control.Label
        TaudecayEditFieldMCMC           matlab.ui.control.NumericEditField
        TaudecaysecLabel_2              matlab.ui.control.Label
        TauriseEditFieldMCMC            matlab.ui.control.NumericEditField
        TaurisesecLabel_2               matlab.ui.control.Label
        UsemanualtimeconstantsCheckBoxMCMC  matlab.ui.control.CheckBox
        ARmodelSwitchMCMC               matlab.ui.control.Switch
        ARmodelSwitch_2Label_4          matlab.ui.control.Label
        DeconvolutionparamsLabelMCMC    matlab.ui.control.Label
        otherTab                        matlab.ui.container.Tab
        FindsimilarcompoptionsPanel     matlab.ui.container.Panel
        UseacceptedcellsCheckBox        matlab.ui.control.CheckBox
        MergemethodDropDown             matlab.ui.control.DropDown
        MergemethodDropDownLabel        matlab.ui.control.Label
        tempcorrtheshEditField          matlab.ui.control.NumericEditField
        tempcorrtheshEditFieldLabel     matlab.ui.control.Label
        spatialcorrthershEditField      matlab.ui.control.NumericEditField
        spatialcorrthershEditFieldLabel  matlab.ui.control.Label
        MergesimilarcompCheckBox        matlab.ui.control.CheckBox
        CatracePanel                    matlab.ui.container.Panel
        PlotLastCSwitch                 matlab.ui.control.Switch
        PlotLastCSwitchLabel            matlab.ui.control.Label
        SmoothRawWindowSpinner          matlab.ui.control.Spinner
        WindowSpinnerLabel              matlab.ui.control.Label
        SmoothRawButton                 matlab.ui.control.StateButton
        PlotSpikesSwitch                matlab.ui.control.Switch
        PlotSpikesSwitchLabel           matlab.ui.control.Label
        PlotRawSwitch                   matlab.ui.control.Switch
        PlotRawSwitchLabel              matlab.ui.control.Label
        PlotCSwitch                     matlab.ui.control.Switch
        PlotCSwitchLabel                matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CurrentComponentTab             matlab.ui.container.Tab
        SkewnessEditField               matlab.ui.control.EditField
        SkewnessEditFieldLabel          matlab.ui.control.Label
        StdnoiseEditField               matlab.ui.control.EditField
        StdnoiseEditFieldLabel          matlab.ui.control.Label
        PeaksaveEditField               matlab.ui.control.EditField
        PeaksaveEditFieldLabel          matlab.ui.control.Label
        FiringstabilityEditField        matlab.ui.control.EditField
        FiringstabilityEditFieldLabel   matlab.ui.control.Label
        SNR2EditField                   matlab.ui.control.EditField
        SNR2EditFieldLabel              matlab.ui.control.Label
        r_valEditField                  matlab.ui.control.EditField
        r_valEditFieldLabel             matlab.ui.control.Label
        CNNprobEditField                matlab.ui.control.EditField
        CNNprobEditFieldLabel           matlab.ui.control.Label
        SNRcaimanEditField              matlab.ui.control.EditField
        SNRcaimanEditFieldLabel         matlab.ui.control.Label
        UIAxes_component                matlab.ui.control.UIAxes
        EvaluateparamsTab               matlab.ui.container.Tab
        SwitchCaimanEvaluate            matlab.ui.control.Switch
        EvaluateComponentsButton        matlab.ui.control.Button
        RejectthresholdPanel            matlab.ui.container.Panel
        EditFieldSkewness               matlab.ui.control.NumericEditField
        CheckBoxSkewness                matlab.ui.control.CheckBox
        EditFieldFiringStability        matlab.ui.control.NumericEditField
        CheckBoxFiringStability         matlab.ui.control.CheckBox
        EditFieldMinSigFrac             matlab.ui.control.NumericEditField
        EditFieldRvalues                matlab.ui.control.NumericEditField
        EditFieldCNN                    matlab.ui.control.NumericEditField
        EditFieldSNR2                   matlab.ui.control.NumericEditField
        EditFieldSNRcaiman              matlab.ui.control.NumericEditField
        CheckBoxMinSigFrac              matlab.ui.control.CheckBox
        CheckBoxRvalues                 matlab.ui.control.CheckBox
        CheckBoxCNN                     matlab.ui.control.CheckBox
        CheckBoxSNR2                    matlab.ui.control.CheckBox
        CheckBoxSNRcaiman               matlab.ui.control.CheckBox
        LampRejectThresh                matlab.ui.control.Lamp
        CaimanevaluatePanel             matlab.ui.container.Panel
        LampCaimanEval                  matlab.ui.control.Lamp
        RvaluesPanel                    matlab.ui.container.Panel
        RvalLowestthreshSpinner         matlab.ui.control.Spinner
        LowestthreshSpinner_3Label      matlab.ui.control.Label
        RvalthreshSpinner               matlab.ui.control.Spinner
        RvalthreshSpinnerLabel          matlab.ui.control.Label
        CNNProbThreshPanel              matlab.ui.container.Panel
        CNNLowestthreshSpinner          matlab.ui.control.Spinner
        LowestthreshSpinner_2Label      matlab.ui.control.Label
        CNNprobthreshSpinner            matlab.ui.control.Spinner
        CNNprobthreshLabel              matlab.ui.control.Label
        SNRPanel                        matlab.ui.container.Panel
        SNRLowestthreshSpinner          matlab.ui.control.Spinner
        LowestthreshLabel               matlab.ui.control.Label
        SNRthreshSpinner                matlab.ui.control.Spinner
        SNRthreshSpinnerLabel           matlab.ui.control.Label
        ParamsDistributionsTab          matlab.ui.container.Tab
        PlotSNR2Button                  matlab.ui.control.Button
        PlotRvalButton                  matlab.ui.control.Button
        PlotCNNButton                   matlab.ui.control.Button
        PlotSNRcaimanButton             matlab.ui.control.Button
        UIAxes_distribution             matlab.ui.control.UIAxes
        ParamsTab                       matlab.ui.container.Tab
        peakbinsizeEditField            matlab.ui.control.EditField
        peakbinsizeEditFieldLabel       matlab.ui.control.Label
        peakstoaveEditField             matlab.ui.control.EditField
        peakstoaveEditFieldLabel        matlab.ui.control.Label
        ProcessingparametersLabel       matlab.ui.control.Label
        gSigEditField                   matlab.ui.control.EditField
        gSigEditFieldLabel              matlab.ui.control.Label
        DimsEditField                   matlab.ui.control.EditField
        DimsEditFieldLabel              matlab.ui.control.Label
        ds_factorEditField              matlab.ui.control.NumericEditField
        ds_factorEditFieldLabel         matlab.ui.control.Label
        pARmodelEditField               matlab.ui.control.NumericEditField
        pARmodelEditFieldLabel          matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        OnACIDparametersLabel           matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        DecayTimeEditField              matlab.ui.control.NumericEditField
        DecayTimeEditFieldLabel         matlab.ui.control.Label
        VisualizationparamsPanel        matlab.ui.container.Panel
        ContourlimitsLabel              matlab.ui.control.Label
        ContourMaxEditField             matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        ContourMinEditField             matlab.ui.control.NumericEditField
        MinLabel                        matlab.ui.control.Label
        ResetManualEditsButton          matlab.ui.control.Button
        ManualEditsSwitch               matlab.ui.control.Switch
        ManualeditsSwitchLabel          matlab.ui.control.Label
        PlotContoursButtonGroup         matlab.ui.container.ButtonGroup
        FiringstabilityButton           matlab.ui.control.ToggleButton
        RvaluesButton                   matlab.ui.control.ToggleButton
        CNNButton                       matlab.ui.control.ToggleButton
        SNR2Button                      matlab.ui.control.ToggleButton
        SNRcaimanButton                 matlab.ui.control.ToggleButton
        NoneButton                      matlab.ui.control.ToggleButton
        BackgroundplotButtonGroup       matlab.ui.container.ButtonGroup
        WcompbkgButton                  matlab.ui.control.ToggleButton
        WeightedcompButton              matlab.ui.control.ToggleButton
        ComponentsButton                matlab.ui.control.ToggleButton
        UIAxesColorPallet               matlab.ui.control.UIAxes
        Panel_load_data                 matlab.ui.container.Panel
        AutoSaveOpsCheckBox             matlab.ui.control.CheckBox
        SavedataButton                  matlab.ui.control.Button
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        LoadDataEditField               matlab.ui.control.EditField
        LoadDataEditFieldLabel          matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        UIAxes_ca_trace                 matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %
    %   For evaluation of caiman-python output data
    %
    %   version 1.2
    %   
    %   Yuriy
    %   edited last 11/19/19
    %       
    %       Need CVX toolbox to run
    %
    %   works with caiman and onACID outputs
    %   make sure to evaluate components but do not sort them in python
    %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        gui_path;
        ops_path;
        file_loc; % Description
        mat_file_loc;
        dims; % Description
        est; % Description
        proc = struct;
        ops = struct;
        disc_est; % Description
        idx_components_all;
        log;
        log_line = 1;
        num_cells;
        num_frames;
        comp_accepted;          % these are including manually added if want
        im_accepted;
        im_accepted_A_data;
        im_accepted_gobj;
        im_rejected;
        im_rejected_A_data;
        im_rejected_gobj;
        comp_gobj;
        bkg_comp_weights;
        bkg_bgkcomp;
        current_cell_num = 1;
        last_cell_num = 1; 
        current_contour;
        contour_resolution = 50;
        curr_contour_params;
        SNR_lim = [0, 20];
        SNR2_lim = [0, 10];
        SNR_abs_lim;
        SNR2_abs_lim;
        cnn_lim = [0, 1];
        rval_lim = [0, 1];
        firing_stab_lim = [0, 1];
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pwd2 = fileparts(which('caiman_sorter.mlapp'));
            %pwd2 = pwd;
            app.gui_path = pwd2;
            
            pwd3 = [pwd2 '\caiman_sorter_functions'];
            if exist(pwd3, 'dir')
                addpath(genpath(pwd3))
                app.ops_path = [pwd2 '\caiman_sorter_options.mat'];
            else
                error('RAFA: You need to move to caiman sorter directory and reopen GUI!!!');
            end    
                
            f_cs_startup(app);
        end

        % Callback function: BrowseButton, LoadDataEditField
        function BrowseButtonPushed(app, event)
            f_cs_browse_button(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            f_cs_load_button_pushed(app);
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            f_cs_save_data(app);
        end

        % Callback function
        function SaveopsButtonPushed(app, event)
            f_cs_save_ops(app);
        end

        % Value changed function: PlotCSwitch
        function PlotCSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: PlotRawSwitch
        function PlotRawSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: SmoothRawWindowSpinner
        function SmoothRawWindowSpinnerValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: SmoothRawButton
        function SmoothRawButtonValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: PlotSpikesSwitch
        function PlotSpikesSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Selection changed function: BackgroundplotButtonGroup
        function BackgroundplotButtonGroupSelectionChanged(app, event)
            f_cs_compute_background_im(app);
            f_cs_update_image_plots(app);
        end

        % Value changed function: PlotLastCSwitch
        function PlotLastCSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Selection changed function: PlotContoursButtonGroup
        function PlotContoursButtonGroupSelectionChanged(app, event)
            f_cs_set_contours(app);
        end

        % Value changed function: ContourMinEditField
        function ContourMinEditFieldValueChanged(app, event)
            temp_val = app.ContourMinEditField.Value;
            if temp_val < app.curr_contour_params.c_abs_lim(1)
                app.ContourMinEditField.Value = app.curr_contour_params.c_abs_lim(1);
            elseif temp_val > app.ContourMaxEditField.Value
                app.ContourMinEditField.Value = app.ContourMaxEditField.Value;
            end
            app.curr_contour_params.c_lim(1) = app.ContourMinEditField.Value;
            f_cs_update_contour_lims(app)
            f_cs_set_contours(app)
        end

        % Value changed function: ContourMaxEditField
        function ContourMaxEditFieldValueChanged(app, event)
            temp_val = app.ContourMaxEditField.Value;
            if temp_val > app.curr_contour_params.c_abs_lim(2)
                app.ContourMaxEditField.Value = app.curr_contour_params.c_abs_lim(2);
            elseif temp_val < app.ContourMinEditField.Value
                app.ContourMaxEditField.Value = app.ContourMinEditField.Value;
            end
            app.curr_contour_params.c_lim(2) = app.ContourMaxEditField.Value;
            f_cs_update_contour_lims(app)
            f_cs_set_contours(app)
        end

        % Button pushed function: ResetManualEditsButton
        function ResetManualEditsButtonPushed(app, event)
            f_cs_reset_manual_edits(app);
        end

        % Value changed function: ManualEditsSwitch
        function ManualEditsSwitchValueChanged(app, event)
            f_cs_update_idx_components(app);
            f_cs_compute_background_im(app);
            f_cs_update_image_plots(app);
            f_cs_set_contours(app);  
            f_cs_plot_curr_contour(app);
        end

        % Button pushed function: EvaluateComponentsButton
        function EvaluateComponentsButtonPushed(app, event)
            f_cs_evaluate_components(app);
            f_cs_update_idx_components(app);
            f_cs_compute_background_im(app);
            f_cs_update_image_plots(app);
            f_cs_set_contours(app);
            f_cs_plot_curr_contour(app);
        end

        % Button pushed function: PlotSNRcaimanButton
        function PlotSNRcaimanButtonPushed(app, event)
            [f,xi] = ksdensity(app.est.SNR_comp);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'SNR distribution');
        end

        % Button pushed function: PlotSNR2Button
        function PlotSNR2ButtonPushed(app, event)
            [f,xi] = ksdensity(app.proc.SNR2_vals);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'SNR distribution');
        end

        % Button pushed function: PlotCNNButton
        function PlotCNNButtonPushed(app, event)
            [f,xi] = ksdensity(app.est.cnn_preds);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'CNN probability distribution');
        end

        % Button pushed function: PlotRvalButton
        function PlotRvalButtonPushed(app, event)
            [f,xi] = ksdensity(app.est.r_values);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'R values distribution');
        end

        % Value changed function: SwitchCaimanEvaluate
        function SwitchCaimanEvaluateValueChanged(app, event)
            f_cs_FlipSwitchCaimanLight(app);
        end

        % Value changed function: CellnumberSpinner
        function CellnumberSpinnerValueChanged(app, event)
            f_cs_cell_num_change(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            f_cs_cell_num_change_key(app, event);
        end

        % Button pushed function: RuncurrentcellButton
        function RuncurrentcellButtonPushed(app, event)
            f_cs_deconvolution(app, app.current_cell_num);
            f_cs_plot_cell_trace(app);
        end

        % Button pushed function: RunacceptedcellsButton
        function RunacceptedcellsButtonPushed(app, event)
            f_cs_deconvolution(app, app.proc.idx_components);
            f_cs_plot_cell_trace(app); 
        end

        % Button pushed function: RunallcellsButton
        function RunallcellsButtonPushed(app, event)
            f_cs_deconvolution(app, app.idx_components_all);
            f_cs_plot_cell_trace(app); 
        end

        % Value changed function: PlotsmoothdfofSwitch
        function PlotsmoothdfofSwitchValueChanged(app, event)
            f_cs_plot_cell_trace(app);           
        end

        % Value changed function: PlotMCMCSwitch
        function PlotMCMCSwitchValueChanged(app, event)
            f_cs_plot_cell_trace(app); 
        end

        % Value changed function: PlotconstfoopsiSwitch
        function PlotconstfoopsiSwitchValueChanged(app, event)
            f_cs_plot_cell_trace(app); 
        end

        % Button pushed function: UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)
            f_cs_plot_cell_trace(app); 
        end

        % Value changed function: 
        % ConvolvewithgaussiankernelCheckBoxSmoothdfdt
        function ConvolvewithgaussiankernelCheckBoxSmoothdfdtValueChanged(app, event)
            f_cs_compute_smooth_dfof(app);
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: GaussKernelSimgaSmoothdfdt
        function GaussKernelSimgaSmoothdfdtValueChanged(app, event)
            f_cs_compute_smooth_dfof(app);
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: RectifyCheckBox
        function RectifyCheckBoxValueChanged(app, event)
            f_cs_compute_smooth_dfof(app);
            f_cs_plot_cell_trace(app);
        end

        % Button pushed function: PlotkernelButtonSmoothdfdt
        function PlotkernelButtonSmoothdfdtPushed(app, event)
            f_cs_plot_gaus_kernel(app.GaussKernelSimgaSmoothdfdt.Value, app.DataframeratefpsEditField.Value);
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            f_cs_compute_smooth_dfof(app);
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: ApplythresholdCheckBox
        function ApplythresholdCheckBoxValueChanged(app, event)
            f_cs_plot_cell_trace(app); 
        end

        % Value changed function: PlotthresholdCheckBox
        function PlotthresholdCheckBoxValueChanged(app, event)
            f_cs_plot_cell_trace(app); 
        end

        % Value changed function: ThresholdmagZEditField
        function ThresholdmagZEditFieldValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: ShiftEditField
        function ShiftEditFieldValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: ConvolvewithgaussiankernelCheckBoxCfoopsi
        function ConvolvewithgaussiankernelCheckBoxCfoopsiValueChanged(app, event)
            f_cs_plot_cell_trace(app); 
        end

        % Button pushed function: PlotkernelButtonCfoopsi
        function PlotkernelButtonCfoopsiPushed(app, event)
            f_cs_plot_gaus_kernel(app.GaussKernelSimgaCfoopsi.Value, app.DataframeratefpsEditField.Value);
        end

        % Value changed function: ShiftEditFieldCfoopsi
        function ShiftEditFieldCfoopsiValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: ScaleEditFieldCfoopsi
        function ScaleEditFieldCfoopsiValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: GaussKernelSimgaCfoopsi
        function GaussKernelSimgaCfoopsiValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Button pushed function: PlotkernelButtonMCMC
        function PlotkernelButtonMCMCPushed(app, event)
            f_cs_plot_gaus_kernel(app.GaussKernelSimgaMCMC.Value, app.DataframeratefpsEditField.Value);
        end

        % Value changed function: GaussKernelSimgaMCMC
        function GaussKernelSimgaMCMCValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: ScaleEditFieldMCMC
        function ScaleEditFieldMCMCValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Value changed function: ShiftEditFieldMCMC
        function ShiftEditFieldMCMCValueChanged(app, event)
            f_cs_plot_cell_trace(app);
        end

        % Callback function
        function PlotMCMCdetailsButtonPushed(app, event)
            if ~isempty(app.proc.deconv.MCMC.SAMP{app.current_cell_num})
                plot_stuff_YS(app.proc.deconv.MCMC.SAMP{app.current_cell_num},app.est.C(app.current_cell_num,:) + app.est.YrA(app.current_cell_num,:))
            end
        end

        % Value changed function: ConvolvewithgaussiankernelCheckBoxMCMC
        function ConvolvewithgaussiankernelCheckBoxMCMCValueChanged(app, event)
            f_cs_plot_cell_trace(app); 
        end

        % Button pushed function: findsimilarcompButton
        function findsimilarcompButtonPushed(app, event)
            f_cs_find_similar_comp(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            f_cs_on_close(app, event);
            delete(app)
        end

        % Value changed function: AutoSaveOpsCheckBox
        function AutoSaveOpsCheckBoxValueChanged(app, event)
            f_cs_autosave_toggled(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_ca_trace = uiaxes(app.UIFigure);
            title(app.UIAxes_ca_trace, 'Ca trace')
            xlabel(app.UIAxes_ca_trace, 'Time (sec)')
            ylabel(app.UIAxes_ca_trace, 'Y')
            app.Panel_load_data = uipanel(app.UIFigure);
            app.BrowseButton = uibutton(app.Panel_load_data, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadDataEditFieldLabel = uilabel(app.Panel_load_data);
            app.LoadDataEditFieldLabel.Text = 'Load Data';
            app.LoadDataEditField = uieditfield(app.Panel_load_data, 'text');
            app.LoadDataEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.LoadDataEditField.Value = 'Load ''.mat'' file with caiman outputs';
            app.LoadButton = uibutton(app.Panel_load_data, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LogTextAreaLabel = uilabel(app.Panel_load_data);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.Panel_load_data);
            app.LogTextArea.Editable = 'off';
            app.SavedataButton = uibutton(app.Panel_load_data, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Text = 'Save data';
            app.AutoSaveOpsCheckBox = uicheckbox(app.Panel_load_data);
            app.AutoSaveOpsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSaveOpsCheckBoxValueChanged, true);
            app.AutoSaveOpsCheckBox.Text = 'Auto save ops';
            app.AutoSaveOpsCheckBox.Value = true;
            app.VisualizationparamsPanel = uipanel(app.UIFigure);
            app.VisualizationparamsPanel.Title = 'Visualization params';
            app.UIAxesColorPallet = uiaxes(app.VisualizationparamsPanel);
            xlabel(app.UIAxesColorPallet, 'Contour color')
            app.UIAxesColorPallet.YTick = [];
            app.UIAxesColorPallet.YTickLabel = '';
            app.BackgroundplotButtonGroup = uibuttongroup(app.VisualizationparamsPanel);
            app.BackgroundplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundplotButtonGroupSelectionChanged, true);
            app.BackgroundplotButtonGroup.Title = 'Background plot';
            app.ComponentsButton = uitogglebutton(app.BackgroundplotButtonGroup);
            app.ComponentsButton.Text = 'Components';
            app.ComponentsButton.Value = true;
            app.WeightedcompButton = uitogglebutton(app.BackgroundplotButtonGroup);
            app.WeightedcompButton.Text = 'Weighted comp';
            app.WcompbkgButton = uitogglebutton(app.BackgroundplotButtonGroup);
            app.WcompbkgButton.Text = 'W comp + bkg';
            app.PlotContoursButtonGroup = uibuttongroup(app.VisualizationparamsPanel);
            app.PlotContoursButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotContoursButtonGroupSelectionChanged, true);
            app.PlotContoursButtonGroup.Title = 'Plot contours';
            app.NoneButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.SNRcaimanButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.SNRcaimanButton.Tooltip = {'SNR computed by caiman (disproportionally large for signals that were added late and therefor cut in NnACID)'};
            app.SNRcaimanButton.Text = 'SNR caiman';
            app.SNR2Button = uitogglebutton(app.PlotContoursButtonGroup);
            app.SNR2Button.Tooltip = {'SNR computed by dividing the peak signal by std of baeline (excluding periods of zeros)'};
            app.SNR2Button.Text = 'SNR2';
            app.CNNButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.CNNButton.Tooltip = {'Probablity from Convolutional Neuronal network that spatial foorprint corresponds to a cell [0 '; '1]'};
            app.CNNButton.Text = 'CNN';
            app.RvaluesButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.RvaluesButton.Tooltip = {'Measure of correlation of demixed cell activity with the total activity in the spatial footprint pixels [-1'; ' 1]'};
            app.RvaluesButton.Text = 'R values';
            app.FiringstabilityButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.FiringstabilityButton.Text = 'Firing stability';
            app.ManualeditsSwitchLabel = uilabel(app.VisualizationparamsPanel);
            app.ManualeditsSwitchLabel.Text = 'Manual edits';
            app.ManualEditsSwitch = uiswitch(app.VisualizationparamsPanel, 'slider');
            app.ManualEditsSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualEditsSwitchValueChanged, true);
            app.ManualEditsSwitch.Value = 'On';
            app.ResetManualEditsButton = uibutton(app.VisualizationparamsPanel, 'push');
            app.ResetManualEditsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetManualEditsButtonPushed, true);
            app.ResetManualEditsButton.Text = 'Reset manual edits';
            app.MinLabel = uilabel(app.VisualizationparamsPanel);
            app.MinLabel.Text = 'Min';
            app.ContourMinEditField = uieditfield(app.VisualizationparamsPanel, 'numeric');
            app.ContourMinEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMinEditFieldValueChanged, true);
            app.MaxLabel = uilabel(app.VisualizationparamsPanel);
            app.MaxLabel.Text = 'Max';
            app.ContourMaxEditField = uieditfield(app.VisualizationparamsPanel, 'numeric');
            app.ContourMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMaxEditFieldValueChanged, true);
            app.ContourlimitsLabel = uilabel(app.VisualizationparamsPanel);
            app.ContourlimitsLabel.Text = 'Contour limits';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CurrentComponentTab = uitab(app.TabGroup);
            app.CurrentComponentTab.Title = 'Current Component';
            app.UIAxes_component = uiaxes(app.CurrentComponentTab);
            title(app.UIAxes_component, 'Cell')
            xlabel(app.UIAxes_component, 'X')
            ylabel(app.UIAxes_component, 'Y')
            app.SNRcaimanEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.SNRcaimanEditFieldLabel.Text = 'SNR caiman';
            app.SNRcaimanEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.SNRcaimanEditField.Editable = 'off';
            app.CNNprobEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.CNNprobEditFieldLabel.Text = 'CNN prob';
            app.CNNprobEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.CNNprobEditField.Editable = 'off';
            app.r_valEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.r_valEditFieldLabel.Text = 'r_val';
            app.r_valEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.r_valEditField.Editable = 'off';
            app.SNR2EditFieldLabel = uilabel(app.CurrentComponentTab);
            app.SNR2EditFieldLabel.Text = 'SNR 2';
            app.SNR2EditField = uieditfield(app.CurrentComponentTab, 'text');
            app.SNR2EditField.Editable = 'off';
            app.FiringstabilityEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.FiringstabilityEditFieldLabel.Text = 'Firing stability';
            app.FiringstabilityEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.FiringstabilityEditField.Editable = 'off';
            app.PeaksaveEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.PeaksaveEditFieldLabel.Text = 'Peaks ave';
            app.PeaksaveEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.PeaksaveEditField.Editable = 'off';
            app.StdnoiseEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.StdnoiseEditFieldLabel.Text = 'Std noise';
            app.StdnoiseEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.StdnoiseEditField.Editable = 'off';
            app.SkewnessEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.SkewnessEditFieldLabel.Text = 'Skewness';
            app.SkewnessEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.SkewnessEditField.Editable = 'off';
            app.EvaluateparamsTab = uitab(app.TabGroup);
            app.EvaluateparamsTab.Title = 'Evaluate params';
            app.CaimanevaluatePanel = uipanel(app.EvaluateparamsTab);
            app.CaimanevaluatePanel.Title = 'Caiman evaluate';
            app.SNRPanel = uipanel(app.CaimanevaluatePanel);
            app.SNRPanel.Title = 'SNR';
            app.SNRthreshSpinnerLabel = uilabel(app.SNRPanel);
            app.SNRthreshSpinnerLabel.Text = {'SNR '; 'thresh'};
            app.SNRthreshSpinner = uispinner(app.SNRPanel);
            app.SNRthreshSpinner.Step = 0.5;
            app.SNRthreshSpinner.Limits = [0 20];
            app.SNRthreshSpinner.Value = 2;
            app.LowestthreshLabel = uilabel(app.SNRPanel);
            app.LowestthreshLabel.Text = {'Lowest'; 'thresh'};
            app.SNRLowestthreshSpinner = uispinner(app.SNRPanel);
            app.SNRLowestthreshSpinner.Step = 0.5;
            app.SNRLowestthreshSpinner.Limits = [0 20];
            app.SNRLowestthreshSpinner.Value = 0.5;
            app.CNNProbThreshPanel = uipanel(app.CaimanevaluatePanel);
            app.CNNProbThreshPanel.Title = 'CNN prob  [0, 1]';
            app.CNNprobthreshLabel = uilabel(app.CNNProbThreshPanel);
            app.CNNprobthreshLabel.Text = {'CNN prob'; 'thresh'};
            app.CNNprobthreshSpinner = uispinner(app.CNNProbThreshPanel);
            app.CNNprobthreshSpinner.Step = 0.05;
            app.CNNprobthreshSpinner.Limits = [0 1];
            app.CNNprobthreshSpinner.Value = 0.99;
            app.LowestthreshSpinner_2Label = uilabel(app.CNNProbThreshPanel);
            app.LowestthreshSpinner_2Label.Text = {'Lowest'; 'thresh'};
            app.CNNLowestthreshSpinner = uispinner(app.CNNProbThreshPanel);
            app.CNNLowestthreshSpinner.Step = 0.05;
            app.CNNLowestthreshSpinner.Limits = [0 1];
            app.CNNLowestthreshSpinner.Value = 0.1;
            app.RvaluesPanel = uipanel(app.CaimanevaluatePanel);
            app.RvaluesPanel.Title = 'R values [0, 1]';
            app.RvalthreshSpinnerLabel = uilabel(app.RvaluesPanel);
            app.RvalthreshSpinnerLabel.Text = {'R val'; 'thresh'};
            app.RvalthreshSpinner = uispinner(app.RvaluesPanel);
            app.RvalthreshSpinner.Step = 0.05;
            app.RvalthreshSpinner.Limits = [0 1];
            app.RvalthreshSpinner.Value = 0.9;
            app.LowestthreshSpinner_3Label = uilabel(app.RvaluesPanel);
            app.LowestthreshSpinner_3Label.Text = {'Lowest'; 'thresh'};
            app.RvalLowestthreshSpinner = uispinner(app.RvaluesPanel);
            app.RvalLowestthreshSpinner.Step = 0.05;
            app.RvalLowestthreshSpinner.Limits = [-1 1];
            app.LampCaimanEval = uilamp(app.CaimanevaluatePanel);
            app.RejectthresholdPanel = uipanel(app.EvaluateparamsTab);
            app.RejectthresholdPanel.Title = 'Reject threshold';
            app.LampRejectThresh = uilamp(app.RejectthresholdPanel);
            app.CheckBoxSNRcaiman = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxSNRcaiman.Text = 'SNR caiman';
            app.CheckBoxSNR2 = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxSNR2.Text = 'SNR2';
            app.CheckBoxSNR2.Value = true;
            app.CheckBoxCNN = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxCNN.Text = 'CNN prob';
            app.CheckBoxRvalues = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxRvalues.Text = 'R values';
            app.CheckBoxMinSigFrac = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxMinSigFrac.Text = 'Min signal frac';
            app.CheckBoxMinSigFrac.Value = true;
            app.EditFieldSNRcaiman = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldSNRcaiman.Limits = [0 Inf];
            app.EditFieldSNRcaiman.Value = 2;
            app.EditFieldSNR2 = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldSNR2.Limits = [0 Inf];
            app.EditFieldSNR2.Value = 2;
            app.EditFieldCNN = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldCNN.Limits = [0 1];
            app.EditFieldCNN.Value = 0.1;
            app.EditFieldRvalues = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldRvalues.Limits = [-1 1];
            app.EditFieldMinSigFrac = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldMinSigFrac.Limits = [0 1];
            app.EditFieldMinSigFrac.Value = 0.5;
            app.CheckBoxFiringStability = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxFiringStability.Text = 'Firing stability';
            app.EditFieldFiringStability = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldFiringStability.Limits = [0 1];
            app.EditFieldFiringStability.Value = 0.01;
            app.CheckBoxSkewness = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxSkewness.Text = 'Min skewness ';
            app.EditFieldSkewness = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldSkewness.Limits = [0 1];
            app.EvaluateComponentsButton = uibutton(app.EvaluateparamsTab, 'push');
            app.EvaluateComponentsButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateComponentsButtonPushed, true);
            app.EvaluateComponentsButton.Text = 'Evaluate components';
            app.SwitchCaimanEvaluate = uiswitch(app.EvaluateparamsTab, 'slider');
            app.SwitchCaimanEvaluate.Items = {'Caiman evaluate', 'Reject threshhold'};
            app.SwitchCaimanEvaluate.ValueChangedFcn = createCallbackFcn(app, @SwitchCaimanEvaluateValueChanged, true);
            app.SwitchCaimanEvaluate.Value = 'Reject threshhold';
            app.ParamsDistributionsTab = uitab(app.TabGroup);
            app.ParamsDistributionsTab.Title = 'Params Distributions';
            app.UIAxes_distribution = uiaxes(app.ParamsDistributionsTab);
            title(app.UIAxes_distribution, 'Density')
            xlabel(app.UIAxes_distribution, 'X')
            ylabel(app.UIAxes_distribution, 'Y')
            app.PlotSNRcaimanButton = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotSNRcaimanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSNRcaimanButtonPushed, true);
            app.PlotSNRcaimanButton.Text = 'Plot SNR caiman';
            app.PlotCNNButton = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotCNNButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCNNButtonPushed, true);
            app.PlotCNNButton.Text = 'Plot CNN';
            app.PlotRvalButton = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotRvalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRvalButtonPushed, true);
            app.PlotRvalButton.Text = 'Plot Rval';
            app.PlotSNR2Button = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotSNR2Button.ButtonPushedFcn = createCallbackFcn(app, @PlotSNR2ButtonPushed, true);
            app.PlotSNR2Button.Text = 'Plot SNR2';
            app.ParamsTab = uitab(app.TabGroup);
            app.ParamsTab.Title = 'Params';
            app.DecayTimeEditFieldLabel = uilabel(app.ParamsTab);
            app.DecayTimeEditFieldLabel.Text = 'Decay Time';
            app.DecayTimeEditField = uieditfield(app.ParamsTab, 'numeric');
            app.DecayTimeEditField.Editable = 'off';
            app.FrameRateEditFieldLabel = uilabel(app.ParamsTab);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ParamsTab, 'numeric');
            app.FrameRateEditField.Editable = 'off';
            app.OnACIDparametersLabel = uilabel(app.ParamsTab);
            app.OnACIDparametersLabel.Text = 'OnACID parameters';
            app.EpochsEditFieldLabel = uilabel(app.ParamsTab);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.ParamsTab, 'numeric');
            app.EpochsEditField.Editable = 'off';
            app.pARmodelEditFieldLabel = uilabel(app.ParamsTab);
            app.pARmodelEditFieldLabel.Text = 'p (AR model)';
            app.pARmodelEditField = uieditfield(app.ParamsTab, 'numeric');
            app.pARmodelEditField.Editable = 'off';
            app.ds_factorEditFieldLabel = uilabel(app.ParamsTab);
            app.ds_factorEditFieldLabel.Text = 'ds_factor';
            app.ds_factorEditField = uieditfield(app.ParamsTab, 'numeric');
            app.ds_factorEditField.Editable = 'off';
            app.DimsEditFieldLabel = uilabel(app.ParamsTab);
            app.DimsEditFieldLabel.Text = 'Dims';
            app.DimsEditField = uieditfield(app.ParamsTab, 'text');
            app.DimsEditField.Editable = 'off';
            app.gSigEditFieldLabel = uilabel(app.ParamsTab);
            app.gSigEditFieldLabel.Text = 'gSig';
            app.gSigEditField = uieditfield(app.ParamsTab, 'text');
            app.gSigEditField.Editable = 'off';
            app.ProcessingparametersLabel = uilabel(app.ParamsTab);
            app.ProcessingparametersLabel.Text = 'Processing parameters';
            app.peakstoaveEditFieldLabel = uilabel(app.ParamsTab);
            app.peakstoaveEditFieldLabel.Text = 'peaks to ave';
            app.peakstoaveEditField = uieditfield(app.ParamsTab, 'text');
            app.peakstoaveEditField.Editable = 'off';
            app.peakbinsizeEditFieldLabel = uilabel(app.ParamsTab);
            app.peakbinsizeEditFieldLabel.Text = 'peak bin size';
            app.peakbinsizeEditField = uieditfield(app.ParamsTab, 'text');
            app.peakbinsizeEditField.Editable = 'off';
            app.CatracePanel = uipanel(app.UIFigure);
            app.CatracePanel.Title = 'Ca trace';
            app.PlotCSwitchLabel = uilabel(app.CatracePanel);
            app.PlotCSwitchLabel.Text = 'Plot C';
            app.PlotCSwitch = uiswitch(app.CatracePanel, 'slider');
            app.PlotCSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotCSwitchValueChanged, true);
            app.PlotCSwitch.Value = 'On';
            app.PlotRawSwitchLabel = uilabel(app.CatracePanel);
            app.PlotRawSwitchLabel.Text = 'Plot Raw';
            app.PlotRawSwitch = uiswitch(app.CatracePanel, 'slider');
            app.PlotRawSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotRawSwitchValueChanged, true);
            app.PlotSpikesSwitchLabel = uilabel(app.CatracePanel);
            app.PlotSpikesSwitchLabel.Text = 'Plot Spikes';
            app.PlotSpikesSwitch = uiswitch(app.CatracePanel, 'slider');
            app.PlotSpikesSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSpikesSwitchValueChanged, true);
            app.SmoothRawButton = uibutton(app.CatracePanel, 'state');
            app.SmoothRawButton.ValueChangedFcn = createCallbackFcn(app, @SmoothRawButtonValueChanged, true);
            app.SmoothRawButton.Text = {'Smooth'; 'raw'};
            app.WindowSpinnerLabel = uilabel(app.CatracePanel);
            app.WindowSpinnerLabel.Text = 'Window';
            app.SmoothRawWindowSpinner = uispinner(app.CatracePanel);
            app.SmoothRawWindowSpinner.Limits = [1 200];
            app.SmoothRawWindowSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothRawWindowSpinnerValueChanged, true);
            app.SmoothRawWindowSpinner.Value = 10;
            app.PlotLastCSwitchLabel = uilabel(app.CatracePanel);
            app.PlotLastCSwitchLabel.Text = 'Plot Last C';
            app.PlotLastCSwitch = uiswitch(app.CatracePanel, 'slider');
            app.PlotLastCSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotLastCSwitchValueChanged, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SpatialcomponentsTab = uitab(app.TabGroup2);
            app.SpatialcomponentsTab.Title = 'Spatial components';
            app.UIAxes_Accepted = uiaxes(app.SpatialcomponentsTab);
            title(app.UIAxes_Accepted, 'Accepted')
            app.UIAxes_Rejected = uiaxes(app.SpatialcomponentsTab);
            title(app.UIAxes_Rejected, 'Rejected')
            app.DeconvolutionTab = uitab(app.TabGroup2);
            app.DeconvolutionTab.Title = 'Deconvolution';
            app.DeonvolutionTypeGroup = uitabgroup(app.DeconvolutionTab);
            app.smoothdfdtTab = uitab(app.DeonvolutionTypeGroup);
            app.smoothdfdtTab.Title = 'smooth df/dt';
            app.SignalextractionparamsLabel = uilabel(app.smoothdfdtTab);
            app.SignalextractionparamsLabel.Text = 'Signal extraction params';
            app.FirstderivativeLabel = uilabel(app.smoothdfdtTab);
            app.FirstderivativeLabel.Text = '1. First derivative';
            app.ConvolvewithgaussiankernelCheckBoxSmoothdfdt = uicheckbox(app.smoothdfdtTab);
            app.ConvolvewithgaussiankernelCheckBoxSmoothdfdt.ValueChangedFcn = createCallbackFcn(app, @ConvolvewithgaussiankernelCheckBoxSmoothdfdtValueChanged, true);
            app.ConvolvewithgaussiankernelCheckBoxSmoothdfdt.Text = 'Convolve with gaussian kernel';
            app.ConvolvewithgaussiankernelCheckBoxSmoothdfdt.Value = true;
            app.Label_5 = uilabel(app.smoothdfdtTab);
            app.Label_5.Text = '2.';
            app.GaussiansigmamsLabel_4 = uilabel(app.smoothdfdtTab);
            app.GaussiansigmamsLabel_4.Text = 'Gaussian sigma (ms)';
            app.GaussKernelSimgaSmoothdfdt = uieditfield(app.smoothdfdtTab, 'numeric');
            app.GaussKernelSimgaSmoothdfdt.ValueChangedFcn = createCallbackFcn(app, @GaussKernelSimgaSmoothdfdtValueChanged, true);
            app.GaussKernelSimgaSmoothdfdt.Value = 100;
            app.PlotkernelButtonSmoothdfdt = uibutton(app.smoothdfdtTab, 'push');
            app.PlotkernelButtonSmoothdfdt.ButtonPushedFcn = createCallbackFcn(app, @PlotkernelButtonSmoothdfdtPushed, true);
            app.PlotkernelButtonSmoothdfdt.Text = 'Plot kernel';
            app.Label_6 = uilabel(app.smoothdfdtTab);
            app.Label_6.Text = '3.';
            app.RectifyCheckBox = uicheckbox(app.smoothdfdtTab);
            app.RectifyCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectifyCheckBoxValueChanged, true);
            app.RectifyCheckBox.Text = 'Rectify';
            app.RectifyCheckBox.Value = true;
            app.NormalizeCheckBox = uicheckbox(app.smoothdfdtTab);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.Label_7 = uilabel(app.smoothdfdtTab);
            app.Label_7.Text = '4.';
            app.PlottingparamsLabel = uilabel(app.smoothdfdtTab);
            app.PlottingparamsLabel.Text = 'Plotting params';
            app.ApplythresholdCheckBox = uicheckbox(app.smoothdfdtTab);
            app.ApplythresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplythresholdCheckBoxValueChanged, true);
            app.ApplythresholdCheckBox.Text = 'Apply threshold';
            app.ThresholdmagZEditFieldLabel = uilabel(app.smoothdfdtTab);
            app.ThresholdmagZEditFieldLabel.Text = 'Threshold mag (Z)';
            app.ThresholdmagZEditField = uieditfield(app.smoothdfdtTab, 'numeric');
            app.ThresholdmagZEditField.Limits = [0 Inf];
            app.ThresholdmagZEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdmagZEditFieldValueChanged, true);
            app.ThresholdmagZEditField.Value = 2;
            app.PlotthresholdCheckBox = uicheckbox(app.smoothdfdtTab);
            app.PlotthresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotthresholdCheckBoxValueChanged, true);
            app.PlotthresholdCheckBox.Text = 'Plot threshold';
            app.PlotthresholdCheckBox.Value = true;
            app.ScaleEditFieldLabel = uilabel(app.smoothdfdtTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.smoothdfdtTab, 'numeric');
            app.ScaleEditField.Limits = [0 Inf];
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = 1;
            app.ShiftEditFieldLabel = uilabel(app.smoothdfdtTab);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.smoothdfdtTab, 'numeric');
            app.ShiftEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftEditFieldValueChanged, true);
            app.constrainedfoopsiTab = uitab(app.DeonvolutionTypeGroup);
            app.constrainedfoopsiTab.Title = 'constrained foopsi';
            app.DeconvolutionparamsLabelCfoopsi = uilabel(app.constrainedfoopsiTab);
            app.DeconvolutionparamsLabelCfoopsi.Text = 'Deconvolution params';
            app.ARmodelSwitch_2Label_3 = uilabel(app.constrainedfoopsiTab);
            app.ARmodelSwitch_2Label_3.Text = 'AR model';
            app.ARmodelSwitchCfoopsi = uiswitch(app.constrainedfoopsiTab, 'slider');
            app.ARmodelSwitchCfoopsi.Items = {'1', '2'};
            app.ARmodelSwitchCfoopsi.Value = '2';
            app.UsemanualtimeconstantsCheckBoxCfoopsi = uicheckbox(app.constrainedfoopsiTab);
            app.UsemanualtimeconstantsCheckBoxCfoopsi.Text = 'Use manual time constants';
            app.TaurisesecLabel = uilabel(app.constrainedfoopsiTab);
            app.TaurisesecLabel.Text = 'Tau rise (sec)';
            app.TauriseEditFieldCfoopsi = uieditfield(app.constrainedfoopsiTab, 'numeric');
            app.TauriseEditFieldCfoopsi.Value = 0.01;
            app.TaudecaysecLabel = uilabel(app.constrainedfoopsiTab);
            app.TaudecaysecLabel.Text = 'Tau decay (sec)';
            app.TaudecayEditFieldCfoopsi = uieditfield(app.constrainedfoopsiTab, 'numeric');
            app.TaudecayEditFieldCfoopsi.Value = 1;
            app.PostDeconvolutionparamsLabelCfoopsi = uilabel(app.constrainedfoopsiTab);
            app.PostDeconvolutionparamsLabelCfoopsi.Text = 'Post deconvolution params';
            app.ConvolvewithgaussiankernelCheckBoxCfoopsi = uicheckbox(app.constrainedfoopsiTab);
            app.ConvolvewithgaussiankernelCheckBoxCfoopsi.ValueChangedFcn = createCallbackFcn(app, @ConvolvewithgaussiankernelCheckBoxCfoopsiValueChanged, true);
            app.ConvolvewithgaussiankernelCheckBoxCfoopsi.Text = 'Convolve with gaussian kernel';
            app.GaussiansigmamsLabel_5 = uilabel(app.constrainedfoopsiTab);
            app.GaussiansigmamsLabel_5.Text = 'Gaussian sigma (ms)';
            app.GaussKernelSimgaCfoopsi = uieditfield(app.constrainedfoopsiTab, 'numeric');
            app.GaussKernelSimgaCfoopsi.ValueChangedFcn = createCallbackFcn(app, @GaussKernelSimgaCfoopsiValueChanged, true);
            app.GaussKernelSimgaCfoopsi.Value = 100;
            app.PlotkernelButtonCfoopsi = uibutton(app.constrainedfoopsiTab, 'push');
            app.PlotkernelButtonCfoopsi.ButtonPushedFcn = createCallbackFcn(app, @PlotkernelButtonCfoopsiPushed, true);
            app.PlotkernelButtonCfoopsi.Text = 'Plot kernel';
            app.PlottingparamsLabelCfoopsi = uilabel(app.constrainedfoopsiTab);
            app.PlottingparamsLabelCfoopsi.Text = 'Plotting params';
            app.ScaleEditField_2Label_3 = uilabel(app.constrainedfoopsiTab);
            app.ScaleEditField_2Label_3.Text = 'Scale';
            app.ScaleEditFieldCfoopsi = uieditfield(app.constrainedfoopsiTab, 'numeric');
            app.ScaleEditFieldCfoopsi.Limits = [0 Inf];
            app.ScaleEditFieldCfoopsi.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldCfoopsiValueChanged, true);
            app.ScaleEditFieldCfoopsi.Value = 1;
            app.ShiftEditField_2Label_3 = uilabel(app.constrainedfoopsiTab);
            app.ShiftEditField_2Label_3.Text = 'Shift';
            app.ShiftEditFieldCfoopsi = uieditfield(app.constrainedfoopsiTab, 'numeric');
            app.ShiftEditFieldCfoopsi.ValueChangedFcn = createCallbackFcn(app, @ShiftEditFieldCfoopsiValueChanged, true);
            app.ShiftEditFieldCfoopsi.Value = -3;
            app.FudgeFactorEditFieldLabel = uilabel(app.constrainedfoopsiTab);
            app.FudgeFactorEditFieldLabel.Text = 'Fudge Factor';
            app.FudgeFactorEditField = uieditfield(app.constrainedfoopsiTab, 'numeric');
            app.FudgeFactorEditField.Limits = [0 1];
            app.FudgeFactorEditField.Value = 0.99;
            app.MethodLabel = uilabel(app.constrainedfoopsiTab);
            app.MethodLabel.Text = 'Method';
            app.DeconvMethodDropDownCfoopsi = uidropdown(app.constrainedfoopsiTab);
            app.MCMCTab = uitab(app.DeonvolutionTypeGroup);
            app.MCMCTab.Title = 'MCMC';
            app.DeconvolutionparamsLabelMCMC = uilabel(app.MCMCTab);
            app.DeconvolutionparamsLabelMCMC.Text = 'Deconvolution params';
            app.ARmodelSwitch_2Label_4 = uilabel(app.MCMCTab);
            app.ARmodelSwitch_2Label_4.Text = 'AR model';
            app.ARmodelSwitchMCMC = uiswitch(app.MCMCTab, 'slider');
            app.ARmodelSwitchMCMC.Items = {'1', '2'};
            app.ARmodelSwitchMCMC.Value = '2';
            app.UsemanualtimeconstantsCheckBoxMCMC = uicheckbox(app.MCMCTab);
            app.UsemanualtimeconstantsCheckBoxMCMC.Text = 'Use manual time constants';
            app.TaurisesecLabel_2 = uilabel(app.MCMCTab);
            app.TaurisesecLabel_2.Text = 'Tau rise (sec)';
            app.TauriseEditFieldMCMC = uieditfield(app.MCMCTab, 'numeric');
            app.TauriseEditFieldMCMC.Limits = [0 1000];
            app.TauriseEditFieldMCMC.Value = 0.01;
            app.TaudecaysecLabel_2 = uilabel(app.MCMCTab);
            app.TaudecaysecLabel_2.Text = 'Tau decay (sec)';
            app.TaudecayEditFieldMCMC = uieditfield(app.MCMCTab, 'numeric');
            app.TaudecayEditFieldMCMC.Limits = [0 1000];
            app.TaudecayEditFieldMCMC.Value = 1;
            app.BNumberofburninsamplesdefault200Label = uilabel(app.MCMCTab);
            app.BNumberofburninsamplesdefault200Label.Text = 'B - Number of burn in samples (default 200)';
            app.MCMCBurnInSamples = uieditfield(app.MCMCTab, 'numeric');
            app.MCMCBurnInSamples.Limits = [0 Inf];
            app.MCMCBurnInSamples.Value = 200;
            app.NNumberofsamplesafterburnindefault500Label = uilabel(app.MCMCTab);
            app.NNumberofsamplesafterburnindefault500Label.Text = 'N - Number of samples after burn in  (default 500)';
            app.MCMCNNumberOfSamples = uieditfield(app.MCMCTab, 'numeric');
            app.MCMCNNumberOfSamples.Limits = [0 Inf];
            app.MCMCNNumberOfSamples.Value = 500;
            app.SaveSamplesOutputsMCMC = uicheckbox(app.MCMCTab);
            app.SaveSamplesOutputsMCMC.Text = 'Save SAMPLES MCMC outputs';
            app.PostDeconvolutionparamsLabelMCMC = uilabel(app.MCMCTab);
            app.PostDeconvolutionparamsLabelMCMC.Text = 'Post deconvolution params';
            app.ConvolvewithgaussiankernelCheckBoxMCMC = uicheckbox(app.MCMCTab);
            app.ConvolvewithgaussiankernelCheckBoxMCMC.ValueChangedFcn = createCallbackFcn(app, @ConvolvewithgaussiankernelCheckBoxMCMCValueChanged, true);
            app.ConvolvewithgaussiankernelCheckBoxMCMC.Text = 'Convolve with gaussian kernel';
            app.GaussiansigmamsLabel_6 = uilabel(app.MCMCTab);
            app.GaussiansigmamsLabel_6.Text = 'Gaussian sigma (ms)';
            app.GaussKernelSimgaMCMC = uieditfield(app.MCMCTab, 'numeric');
            app.GaussKernelSimgaMCMC.ValueChangedFcn = createCallbackFcn(app, @GaussKernelSimgaMCMCValueChanged, true);
            app.GaussKernelSimgaMCMC.Value = 100;
            app.PlotkernelButtonMCMC = uibutton(app.MCMCTab, 'push');
            app.PlotkernelButtonMCMC.ButtonPushedFcn = createCallbackFcn(app, @PlotkernelButtonMCMCPushed, true);
            app.PlotkernelButtonMCMC.Text = 'Plot kernel';
            app.PlottingparamsLabelMCMC = uilabel(app.MCMCTab);
            app.PlottingparamsLabelMCMC.Text = 'Plotting params';
            app.ScaleEditField_2Label_4 = uilabel(app.MCMCTab);
            app.ScaleEditField_2Label_4.Text = 'Scale';
            app.ScaleEditFieldMCMC = uieditfield(app.MCMCTab, 'numeric');
            app.ScaleEditFieldMCMC.Limits = [0 Inf];
            app.ScaleEditFieldMCMC.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldMCMCValueChanged, true);
            app.ScaleEditFieldMCMC.Value = 3;
            app.ShiftEditField_2Label_4 = uilabel(app.MCMCTab);
            app.ShiftEditField_2Label_4.Text = 'Shift';
            app.ShiftEditFieldMCMC = uieditfield(app.MCMCTab, 'numeric');
            app.ShiftEditFieldMCMC.ValueChangedFcn = createCallbackFcn(app, @ShiftEditFieldMCMCValueChanged, true);
            app.ShiftEditFieldMCMC.Value = -1;
            app.PlotMCMCSAMPLESdetailsButton = uibutton(app.MCMCTab, 'push');
            app.PlotMCMCSAMPLESdetailsButton.Enable = 'off';
            app.PlotMCMCSAMPLESdetailsButton.Text = 'Plot MCMC SAMPLES details';
            app.CellEditFieldLabel = uilabel(app.DeconvolutionTab);
            app.CellEditFieldLabel.Text = 'Cell';
            app.CellEditField = uieditfield(app.DeconvolutionTab, 'numeric');
            app.CellEditField.Editable = 'off';
            app.CellEditField.Value = 1;
            app.Estimatedtimeconstantar1Label = uilabel(app.DeconvolutionTab);
            app.Estimatedtimeconstantar1Label.Text = 'Estimated time constant ar1';
            app.TaudecaysecLabel_4 = uilabel(app.DeconvolutionTab);
            app.TaudecaysecLabel_4.Text = 'Tau decay sec';
            app.TaudecayEditFieldAR1 = uieditfield(app.DeconvolutionTab, 'numeric');
            app.TaudecayEditFieldAR1.Editable = 'off';
            app.Estimatedtimeconstantsar2Label = uilabel(app.DeconvolutionTab);
            app.Estimatedtimeconstantsar2Label.Text = 'Estimated time constants ar2';
            app.TaudecaysecLabel_3 = uilabel(app.DeconvolutionTab);
            app.TaudecaysecLabel_3.Text = 'Tau decay (sec)';
            app.TaudecayEditFieldAR2 = uieditfield(app.DeconvolutionTab, 'numeric');
            app.TaudecayEditFieldAR2.Editable = 'off';
            app.TaurisesecLabel_3 = uilabel(app.DeconvolutionTab);
            app.TaurisesecLabel_3.Text = 'Tau rise (sec)';
            app.TauriseEditFieldAR2 = uieditfield(app.DeconvolutionTab, 'numeric');
            app.TauriseEditFieldAR2.Editable = 'off';
            app.DataframeratefpsEditFieldLabel = uilabel(app.DeconvolutionTab);
            app.DataframeratefpsEditFieldLabel.Text = 'Data frame rate (fps)';
            app.DataframeratefpsEditField = uieditfield(app.DeconvolutionTab, 'numeric');
            app.DataframeratefpsEditField.Value = 30;
            app.RuncurrentcellButton = uibutton(app.DeconvolutionTab, 'push');
            app.RuncurrentcellButton.ButtonPushedFcn = createCallbackFcn(app, @RuncurrentcellButtonPushed, true);
            app.RuncurrentcellButton.Text = 'Run current cell';
            app.RunacceptedcellsButton = uibutton(app.DeconvolutionTab, 'push');
            app.RunacceptedcellsButton.ButtonPushedFcn = createCallbackFcn(app, @RunacceptedcellsButtonPushed, true);
            app.RunacceptedcellsButton.Text = 'Run accepted cells';
            app.RunallcellsButton = uibutton(app.DeconvolutionTab, 'push');
            app.RunallcellsButton.ButtonPushedFcn = createCallbackFcn(app, @RunallcellsButtonPushed, true);
            app.RunallcellsButton.Text = 'Run all cells';
            app.OverwriteCheckBox = uicheckbox(app.DeconvolutionTab);
            app.OverwriteCheckBox.Text = 'Overwrite';
            app.PlotsmoothdfofSwitchLabel = uilabel(app.DeconvolutionTab);
            app.PlotsmoothdfofSwitchLabel.Text = 'Plot smooth dfof';
            app.PlotsmoothdfofSwitch = uiswitch(app.DeconvolutionTab, 'slider');
            app.PlotsmoothdfofSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotsmoothdfofSwitchValueChanged, true);
            app.PlotconstfoopsiSwitchLabel = uilabel(app.DeconvolutionTab);
            app.PlotconstfoopsiSwitchLabel.Text = 'Plot const foopsi';
            app.PlotconstfoopsiSwitch = uiswitch(app.DeconvolutionTab, 'slider');
            app.PlotconstfoopsiSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotconstfoopsiSwitchValueChanged, true);
            app.PlotconstfoopsiSwitch.Enable = 'off';
            app.PlotMCMCSwitchLabel = uilabel(app.DeconvolutionTab);
            app.PlotMCMCSwitchLabel.Text = 'Plot MCMC';
            app.PlotMCMCSwitch = uiswitch(app.DeconvolutionTab, 'slider');
            app.PlotMCMCSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotMCMCSwitchValueChanged, true);
            app.PlotMCMCSwitch.Enable = 'off';
            app.UpdateplotsButton = uibutton(app.DeconvolutionTab, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.otherTab = uitab(app.TabGroup2);
            app.otherTab.Title = 'other';
            app.FindsimilarcompoptionsPanel = uipanel(app.otherTab);
            app.FindsimilarcompoptionsPanel.Title = 'Find similar comp options';
            app.MergesimilarcompCheckBox = uicheckbox(app.FindsimilarcompoptionsPanel);
            app.MergesimilarcompCheckBox.Text = 'Merge similar comp';
            app.MergesimilarcompCheckBox.Value = true;
            app.spatialcorrthershEditFieldLabel = uilabel(app.FindsimilarcompoptionsPanel);
            app.spatialcorrthershEditFieldLabel.Text = 'spatial corr thersh';
            app.spatialcorrthershEditField = uieditfield(app.FindsimilarcompoptionsPanel, 'numeric');
            app.spatialcorrthershEditField.Limits = [-1 1];
            app.spatialcorrthershEditField.Value = 0.3;
            app.tempcorrtheshEditFieldLabel = uilabel(app.FindsimilarcompoptionsPanel);
            app.tempcorrtheshEditFieldLabel.Text = 'temp corr thesh';
            app.tempcorrtheshEditField = uieditfield(app.FindsimilarcompoptionsPanel, 'numeric');
            app.tempcorrtheshEditField.Limits = [-1 1];
            app.tempcorrtheshEditField.Value = 0.3;
            app.MergemethodDropDownLabel = uilabel(app.FindsimilarcompoptionsPanel);
            app.MergemethodDropDownLabel.Text = 'Merge method';
            app.MergemethodDropDown = uidropdown(app.FindsimilarcompoptionsPanel);
            app.MergemethodDropDown.Items = {'weighted ave', 'full mean corr', 'svd', 'nmf', 'choose best snr'};
            app.MergemethodDropDown.Value = 'weighted ave';
            app.UseacceptedcellsCheckBox = uicheckbox(app.FindsimilarcompoptionsPanel);
            app.UseacceptedcellsCheckBox.Text = 'Use accepted cells';
            app.UseacceptedcellsCheckBox.Value = true;
            app.CellselectionPanel = uipanel(app.UIFigure);
            app.CellselectionPanel.Title = 'Cell selection';
            app.PressupdownkeytochangecellnumButton = uibutton(app.CellselectionPanel, 'state');
            app.PressupdownkeytochangecellnumButton.Text = 'Press up/down key to change cell num';
            app.CellnumberSpinnerLabel = uilabel(app.CellselectionPanel);
            app.CellnumberSpinnerLabel.Text = 'Cell number';
            app.CellnumberSpinner = uispinner(app.CellselectionPanel);
            app.CellnumberSpinner.Limits = [1 Inf];
            app.CellnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @CellnumberSpinnerValueChanged, true);
            app.CellnumberSpinner.Value = 1;
            app.CellcategoryButtonGroup = uibuttongroup(app.CellselectionPanel);
            app.CellcategoryButtonGroup.Title = 'Cell category';
            app.AcceptedButton = uitogglebutton(app.CellcategoryButtonGroup);
            app.AcceptedButton.Text = 'Accepted';
            app.AcceptedButton.Value = true;
            app.RejectedButton = uitogglebutton(app.CellcategoryButtonGroup);
            app.RejectedButton.Text = 'Rejected';
            app.AllButton = uitogglebutton(app.CellcategoryButtonGroup);
            app.AllButton.Text = 'All';
            app.findsimilarcompButton = uibutton(app.UIFigure, 'push');
            app.findsimilarcompButton.ButtonPushedFcn = createCallbackFcn(app, @findsimilarcompButtonPushed, true);
            app.findsimilarcompButton.Text = 'find similar comp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = caiman_sorter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end