classdef caiman_sorter_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_load_data                 matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        LoadDataEditFieldLabel          matlab.ui.control.Label
        LoadDataEditField               matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        SavedataButton                  matlab.ui.control.Button
        SaveopsButton                   matlab.ui.control.Button
        UIAxes_ca_trace                 matlab.ui.control.UIAxes
        ParametersPanel                 matlab.ui.container.Panel
        BackgroundplotButtonGroup       matlab.ui.container.ButtonGroup
        ComponentsButton                matlab.ui.control.ToggleButton
        WeightedcompButton              matlab.ui.control.ToggleButton
        WcompbkgButton                  matlab.ui.control.ToggleButton
        PlotContoursButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.ToggleButton
        SNRcaimanButton                 matlab.ui.control.ToggleButton
        SNR2Button                      matlab.ui.control.ToggleButton
        CNNButton                       matlab.ui.control.ToggleButton
        RvaluesButton                   matlab.ui.control.ToggleButton
        FiringstabilityButton           matlab.ui.control.ToggleButton
        ManualeditsSwitchLabel          matlab.ui.control.Label
        ManualEditsSwitch               matlab.ui.control.Switch
        ResetManualEditsButton          matlab.ui.control.Button
        MinLabel                        matlab.ui.control.Label
        ContourMinEditField             matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        ContourMaxEditField             matlab.ui.control.NumericEditField
        UIAxesColorPallet               matlab.ui.control.UIAxes
        ContourlimitsLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CurrentComponentTab             matlab.ui.container.Tab
        UIAxes_component                matlab.ui.control.UIAxes
        SNRcaimanEditFieldLabel         matlab.ui.control.Label
        SNRcaimanEditField              matlab.ui.control.EditField
        CNNprobEditFieldLabel           matlab.ui.control.Label
        CNNprobEditField                matlab.ui.control.EditField
        r_valEditFieldLabel             matlab.ui.control.Label
        r_valEditField                  matlab.ui.control.EditField
        SNR2EditFieldLabel              matlab.ui.control.Label
        SNR2EditField                   matlab.ui.control.EditField
        FiringstabilityEditFieldLabel   matlab.ui.control.Label
        FiringstabilityEditField        matlab.ui.control.EditField
        PeaksaveEditFieldLabel          matlab.ui.control.Label
        PeaksaveEditField               matlab.ui.control.EditField
        StdnoiseEditFieldLabel          matlab.ui.control.Label
        StdnoiseEditField               matlab.ui.control.EditField
        EvaluateparamsTab               matlab.ui.container.Tab
        CaimanevaluatePanel             matlab.ui.container.Panel
        SNRPanel                        matlab.ui.container.Panel
        SNRthreshSpinnerLabel           matlab.ui.control.Label
        SNRthreshSpinner                matlab.ui.control.Spinner
        LowestthreshLabel               matlab.ui.control.Label
        SNRLowestthreshSpinner          matlab.ui.control.Spinner
        CNNProbThreshPanel              matlab.ui.container.Panel
        CNNprobthreshLabel              matlab.ui.control.Label
        CNNprobthreshSpinner            matlab.ui.control.Spinner
        LowestthreshSpinner_2Label      matlab.ui.control.Label
        CNNLowestthreshSpinner          matlab.ui.control.Spinner
        RvaluesPanel                    matlab.ui.container.Panel
        RvalthreshSpinnerLabel          matlab.ui.control.Label
        RvalthreshSpinner               matlab.ui.control.Spinner
        LowestthreshSpinner_3Label      matlab.ui.control.Label
        RvalLowestthreshSpinner         matlab.ui.control.Spinner
        LampCaimanEval                  matlab.ui.control.Lamp
        RejectthresholdPanel            matlab.ui.container.Panel
        LampRejectThresh                matlab.ui.control.Lamp
        CheckBoxSNRcaiman               matlab.ui.control.CheckBox
        CheckBoxSNR2                    matlab.ui.control.CheckBox
        CheckBoxCNN                     matlab.ui.control.CheckBox
        CheckBoxRvalues                 matlab.ui.control.CheckBox
        CheckBoxMinSigFrac              matlab.ui.control.CheckBox
        EditFieldSNRcaiman              matlab.ui.control.NumericEditField
        EditFieldSNR2                   matlab.ui.control.NumericEditField
        EditFieldCNN                    matlab.ui.control.NumericEditField
        EditFieldRvalues                matlab.ui.control.NumericEditField
        EditFieldMinSigFrac             matlab.ui.control.NumericEditField
        CheckBoxFiringStability         matlab.ui.control.CheckBox
        EditFieldFiringStability        matlab.ui.control.NumericEditField
        EvaluateComponentsButton        matlab.ui.control.Button
        SwitchCaimanEvaluate            matlab.ui.control.Switch
        ParamsDistributionsTab          matlab.ui.container.Tab
        UIAxes_distribution             matlab.ui.control.UIAxes
        PlotSNRcaimanButton             matlab.ui.control.Button
        PlotCNNButton                   matlab.ui.control.Button
        PlotRvalButton                  matlab.ui.control.Button
        PlotSNR2Button                  matlab.ui.control.Button
        ParamsTab                       matlab.ui.container.Tab
        DecayTimeEditFieldLabel         matlab.ui.control.Label
        DecayTimeEditField              matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        OnACIDparametersLabel           matlab.ui.control.Label
        EpochsEditFieldLabel            matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        pARmodelEditFieldLabel          matlab.ui.control.Label
        pARmodelEditField               matlab.ui.control.NumericEditField
        ds_factorEditFieldLabel         matlab.ui.control.Label
        ds_factorEditField              matlab.ui.control.NumericEditField
        DimsEditFieldLabel              matlab.ui.control.Label
        DimsEditField                   matlab.ui.control.EditField
        gSigEditFieldLabel              matlab.ui.control.Label
        gSigEditField                   matlab.ui.control.EditField
        ProcessingparametersLabel       matlab.ui.control.Label
        peakstoaveEditFieldLabel        matlab.ui.control.Label
        peakstoaveEditField             matlab.ui.control.EditField
        peakbinsizeEditFieldLabel       matlab.ui.control.Label
        peakbinsizeEditField            matlab.ui.control.EditField
        TemporaltracePanel              matlab.ui.container.Panel
        PlotCSwitchLabel                matlab.ui.control.Label
        PlotCSwitch                     matlab.ui.control.Switch
        PlotRawSwitchLabel              matlab.ui.control.Label
        PlotRawSwitch                   matlab.ui.control.Switch
        PlotSpikesSwitchLabel           matlab.ui.control.Label
        PlotSpikesSwitch                matlab.ui.control.Switch
        SmoothRawButton                 matlab.ui.control.StateButton
        WindowSpinnerLabel              matlab.ui.control.Label
        SmoothRawWindowSpinner          matlab.ui.control.Spinner
        PlotLastCSwitchLabel            matlab.ui.control.Label
        PlotLastCSwitch                 matlab.ui.control.Switch
        TabGroup2                       matlab.ui.container.TabGroup
        SpatialcomponentsTab            matlab.ui.container.Tab
        UIAxes_Accepted                 matlab.ui.control.UIAxes
        UIAxes_Rejected                 matlab.ui.control.UIAxes
        DeconvolutionTab                matlab.ui.container.Tab
        DeonvolutionTypeGroup           matlab.ui.container.TabGroup
        smoothdfdtTab                   matlab.ui.container.Tab
        NormalizeCheckBox               matlab.ui.control.CheckBox
        ScaleEditFieldLabel             matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        RectifyCheckBox                 matlab.ui.control.CheckBox
        SmoothingwindowmsEditFieldLabel  matlab.ui.control.Label
        SmoothingwindowmsEditField      matlab.ui.control.NumericEditField
        PlotthresholdCheckBox           matlab.ui.control.CheckBox
        ThresholdmagZEditFieldLabel     matlab.ui.control.Label
        ThresholdmagZEditField          matlab.ui.control.NumericEditField
        ApplythresholdCheckBox          matlab.ui.control.CheckBox
        ShiftEditFieldLabel             matlab.ui.control.Label
        ShiftEditField                  matlab.ui.control.NumericEditField
        PlottingparamsLabel             matlab.ui.control.Label
        SignalextractionparamsLabel     matlab.ui.control.Label
        PlotsmoothdfofSwitchLabel       matlab.ui.control.Label
        PlotsmoothdfofSwitch            matlab.ui.control.Switch
        UpdateplotsButton               matlab.ui.control.Button
        CellEditFieldLabel              matlab.ui.control.Label
        CellEditField                   matlab.ui.control.NumericEditField
        Estimatedtimeconstantar1Label   matlab.ui.control.Label
        TaudecayEditFieldLabel          matlab.ui.control.Label
        TaudecayEditFieldAR1            matlab.ui.control.NumericEditField
        TaudecayEditField_3Label        matlab.ui.control.Label
        TaudecayEditFieldAR2            matlab.ui.control.NumericEditField
        TauriseEditField_3Label         matlab.ui.control.Label
        TauriseEditFieldAR2             matlab.ui.control.NumericEditField
        Estimatedtimeconstantsar2Label_2  matlab.ui.control.Label
        DataframeratefpsEditFieldLabel  matlab.ui.control.Label
        DataframeratefpsEditField       matlab.ui.control.NumericEditField
        RunallcellsButton_2             matlab.ui.control.Button
        CellnumberSpinnerLabel          matlab.ui.control.Label
        CellnumberSpinner               matlab.ui.control.Spinner
        PressupdownkeytochangecellnumButton  matlab.ui.control.StateButton
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %
    %   For evaluation of caiman-python output data
    %
    %   version 1.2
    %   
    %   Yuriy
    %   edited last 11/19/19
    %       
    %       Need CVX toolbox to run
    %
    %   works with caiman and onACID outputs
    %   make sure to evaluate components but do not sort them in python
    %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        file_loc; % Description
        mat_file_loc;
        dims; % Description
        est; % Description
        proc = struct;
        ops = struct;
        disc_est; % Description
        A3d;
        idx_components_all;
        log;
        log_line = 1;
        num_cells;
        num_frames;
        comp_accepted;          % these are including manually added if want
        im_accepted;
        im_accepted_A_data;
        im_accepted_gobj;
        im_rejected;
        im_rejected_A_data;
        im_rejected_gobj;
        comp_gobj;
        bkg_comp_weights;
        bkg_bgkcomp;
        current_cell_num = 1;
        last_cell_num = 1; 
        current_contour;
        contour_resolution = 50;
        curr_contour_params;
        SNR_lim = [0, 20];
        SNR2_lim = [0, 10];
        SNR_abs_lim;
        SNR2_abs_lim;
        cnn_lim = [0, 1];
        rval_lim = [0, 1];
        firing_stab_lim = [0, 1];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Caiman Sorter GUI started');           
            app.ops = f_cs_collect_ops(app, app.ops);
            ops_path = [pwd '\caiman_sorter_options.mat'];
            app.ops.ops_path = ops_path;
            
            if exist(ops_path, 'file')
                ops_temp = load(ops_path, 'ops');
                app.ops = ops_temp.ops;
                f_cs_update_log(app, ['ops loaded path: ' app.ops.ops_path]);
                f_cs_write_ops(app, app.ops);
            end
        end

        % Callback function: BrowseButton, LoadDataEditField
        function BrowseButtonPushed(app, event)
            if isfield(app.ops, 'browse_path')
                [file_name,path] = uigetfile([app.ops.browse_path '*.mat;*.hdf5']);
            else
                [file_name,path] = uigetfile({'*.mat;*.hdf5'; '*.mat'; '*.hdf5'});
            end
            if path
                app.ops.browse_path = path;
            end
            drawnow; pause(0.05);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if isequal(file_name,0)
               disp('User selected Cancel');
            else
               app.file_loc = fullfile(path,file_name);
               disp(['User selected ', app.file_loc]);
               app.LoadDataEditField.Value = app.file_loc;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            f_cs_load_button_pushed(app);
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            app.ops = f_cs_collect_ops(app, app.ops);
            ops = app.ops;
            proc = app.proc;
            est = app.est;
            
            if isfield(app, 'SaveSamplesOutputsMCMC')
                if ~app.SaveSamplesOutputsMCMC.Value
                    proc.deconv.MCMC.SAMP = [];
                end
            end
            
            if exist(app.mat_file_loc, 'file')
                save(app.mat_file_loc, 'est', 'proc', 'ops');
            else
                save(app.mat_file_loc, 'est', 'proc', 'ops');
            end
            f_cs_update_log(app, 'Saved data');
        end

        % Button pushed function: SaveopsButton
        function SaveopsButtonPushed(app, event)
            app.ops = f_cs_collect_ops(app, app.ops);
            ops = app.ops;
            try 
                if ~exist(app.ops.ops_path, 'file')
                    save(app.ops.ops_path, 'ops');
                else
                    save(app.ops.ops_path, 'ops', '-append');
                end
                f_cs_update_log(app, ['Ops saved in: ' app.ops.ops_path]);
            catch
                f_cs_update_log(app, ['Unable to save ops in ' app.ops.ops_path]);
            end

        end

        % Value changed function: PlotCSwitch
        function PlotCSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: PlotRawSwitch
        function PlotRawSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: SmoothRawWindowSpinner
        function SmoothRawWindowSpinnerValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: SmoothRawButton
        function SmoothRawButtonValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Value changed function: PlotSpikesSwitch
        function PlotSpikesSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Selection changed function: BackgroundplotButtonGroup
        function BackgroundplotButtonGroupSelectionChanged(app, event)
            f_cs_compute_background_im(app);
            f_cs_update_image_plots(app);
        end

        % Value changed function: PlotLastCSwitch
        function PlotLastCSwitchValueChanged(app, event)
            f_cs_update_curr_cell_info(app);
        end

        % Selection changed function: PlotContoursButtonGroup
        function PlotContoursButtonGroupSelectionChanged(app, event)
            f_cs_set_contours(app);
        end

        % Value changed function: ContourMinEditField
        function ContourMinEditFieldValueChanged(app, event)
            temp_val = app.ContourMinEditField.Value;
            if temp_val < app.curr_contour_params.c_abs_lim(1)
                app.ContourMinEditField.Value = app.curr_contour_params.c_abs_lim(1);
            elseif temp_val > app.ContourMaxEditField.Value
                app.ContourMinEditField.Value = app.ContourMaxEditField.Value;
            end
            app.curr_contour_params.c_lim(1) = app.ContourMinEditField.Value;
            f_cs_update_contour_lims(app)
            f_cs_set_contours(app)
        end

        % Value changed function: ContourMaxEditField
        function ContourMaxEditFieldValueChanged(app, event)
            temp_val = app.ContourMaxEditField.Value;
            if temp_val > app.curr_contour_params.c_abs_lim(2)
                app.ContourMaxEditField.Value = app.curr_contour_params.c_abs_lim(2);
            elseif temp_val < app.ContourMinEditField.Value
                app.ContourMaxEditField.Value = app.ContourMinEditField.Value;
            end
            app.curr_contour_params.c_lim(2) = app.ContourMaxEditField.Value;
            f_cs_update_contour_lims(app)
            f_cs_set_contours(app)
        end

        % Button pushed function: ResetManualEditsButton
        function ResetManualEditsButtonPushed(app, event)
            app.proc.idx_manual = [];
            app.proc.idx_manual_bad = [];
            f_cs_update_idx_components(app);
            f_cs_compute_background_im(app);
            f_cs_update_image_plots(app);
            f_cs_set_contours(app);   
            f_cs_plot_curr_contour(app);
        end

        % Value changed function: ManualEditsSwitch
        function ManualEditsSwitchValueChanged(app, event)
            f_cs_update_idx_components(app);
            f_cs_compute_background_im(app);
            f_cs_update_image_plots(app);
            f_cs_set_contours(app);  
            f_cs_plot_curr_contour(app);
        end

        % Button pushed function: EvaluateComponentsButton
        function EvaluateComponentsButtonPushed(app, event)
            f_cs_evaluate_components(app);
            f_cs_update_idx_components(app);
            f_cs_compute_background_im(app);
            f_cs_update_image_plots(app);
            f_cs_set_contours(app);
            f_cs_plot_curr_contour(app);
        end

        % Button pushed function: PlotSNRcaimanButton
        function PlotSNRcaimanButtonPushed(app, event)
            [f,xi] = ksdensity(app.est.SNR_comp);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'SNR distribution');
        end

        % Button pushed function: PlotSNR2Button
        function PlotSNR2ButtonPushed(app, event)
            [f,xi] = ksdensity(app.proc.SNR2_vals);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'SNR distribution');
        end

        % Button pushed function: PlotCNNButton
        function PlotCNNButtonPushed(app, event)
            [f,xi] = ksdensity(app.est.cnn_preds);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'CNN probability distribution');
        end

        % Button pushed function: PlotRvalButton
        function PlotRvalButtonPushed(app, event)
            [f,xi] = ksdensity(app.est.r_values);
            plot(app.UIAxes_distribution,xi, f);
            title(app.UIAxes_distribution, 'R values distribution');
        end

        % Value changed function: SwitchCaimanEvaluate
        function SwitchCaimanEvaluateValueChanged(app, event)
            f_FlipSwitchCaimanLight(app);
        end

        % Value changed function: CellnumberSpinner
        function CellnumberSpinnerValueChanged(app, event)
            temp_val = round(app.CellnumberSpinner.Value);
            temp_val = max(temp_val, 1);
            temp_val = min(temp_val, app.proc.num_cells);
            app.current_cell_num = temp_val;
            app.CellnumberSpinner.Value = temp_val;
            f_cs_update_curr_cell_info(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.PressupdownkeytochangecellnumButton.Value
                if strcmp(event.Key, 'uparrow')
                    app.current_cell_num = min(app.current_cell_num + 1, app.proc.num_cells);
                    f_cs_update_curr_cell_info(app);
                elseif strcmp(event.Key, 'downarrow')
                    app.current_cell_num = max(app.current_cell_num - 1, 1);
                    f_cs_update_curr_cell_info(app);
                end
            end
        end

        % Button pushed function: RunallcellsButton_2
        function RunallcellsButtonPushed(app, event)
            f_cs_deconvolution_light(app);
            f_cs_plot_cell_trace(app); 
        end

        % Value changed function: PlotsmoothdfofSwitch
        function PlotsmoothdfofSwitchValueChanged(app, event)
            f_cs_plot_cell_trace(app);           
        end

        % Button pushed function: UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)
            f_cs_plot_cell_trace(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Panel_load_data = uipanel(app.UIFigure);
            app.BrowseButton = uibutton(app.Panel_load_data, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadDataEditFieldLabel = uilabel(app.Panel_load_data);
            app.LoadDataEditFieldLabel.Text = 'Load Data';
            app.LoadDataEditField = uieditfield(app.Panel_load_data, 'text');
            app.LoadDataEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.LoadDataEditField.Value = 'Load ''.mat'' file with caiman outputs';
            app.LoadButton = uibutton(app.Panel_load_data, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LogTextAreaLabel = uilabel(app.Panel_load_data);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.Panel_load_data);
            app.LogTextArea.Editable = 'off';
            app.SavedataButton = uibutton(app.Panel_load_data, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Text = 'Save data';
            app.SaveopsButton = uibutton(app.Panel_load_data, 'push');
            app.SaveopsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveopsButtonPushed, true);
            app.SaveopsButton.Text = 'Save ops';
            app.UIAxes_ca_trace = uiaxes(app.UIFigure);
            title(app.UIAxes_ca_trace, 'Ca trace')
            xlabel(app.UIAxes_ca_trace, 'Time (sec)')
            ylabel(app.UIAxes_ca_trace, 'Y')
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.BackgroundplotButtonGroup = uibuttongroup(app.ParametersPanel);
            app.BackgroundplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundplotButtonGroupSelectionChanged, true);
            app.BackgroundplotButtonGroup.Title = 'Background plot';
            app.ComponentsButton = uitogglebutton(app.BackgroundplotButtonGroup);
            app.ComponentsButton.Text = 'Components';
            app.ComponentsButton.Value = true;
            app.WeightedcompButton = uitogglebutton(app.BackgroundplotButtonGroup);
            app.WeightedcompButton.Text = 'Weighted comp';
            app.WcompbkgButton = uitogglebutton(app.BackgroundplotButtonGroup);
            app.WcompbkgButton.Text = 'W comp + bkg';
            app.PlotContoursButtonGroup = uibuttongroup(app.ParametersPanel);
            app.PlotContoursButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotContoursButtonGroupSelectionChanged, true);
            app.PlotContoursButtonGroup.Title = 'Plot contours';
            app.NoneButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.SNRcaimanButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.SNRcaimanButton.Tooltip = {'SNR computed by caiman (disproportionally large for signals that were added late and therefor cut in NnACID)'};
            app.SNRcaimanButton.Text = 'SNR caiman';
            app.SNR2Button = uitogglebutton(app.PlotContoursButtonGroup);
            app.SNR2Button.Tooltip = {'SNR computed by dividing the peak signal by std of baeline (excluding periods of zeros)'};
            app.SNR2Button.Text = 'SNR2';
            app.CNNButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.CNNButton.Tooltip = {'Probablity from Convolutional Neuronal network that spatial foorprint corresponds to a cell [0 '; '1]'};
            app.CNNButton.Text = 'CNN';
            app.RvaluesButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.RvaluesButton.Tooltip = {'Measure of correlation of demixed cell activity with the total activity in the spatial footprint pixels [-1'; ' 1]'};
            app.RvaluesButton.Text = 'R values';
            app.FiringstabilityButton = uitogglebutton(app.PlotContoursButtonGroup);
            app.FiringstabilityButton.Text = 'Firing stability';
            app.ManualeditsSwitchLabel = uilabel(app.ParametersPanel);
            app.ManualeditsSwitchLabel.Text = 'Manual edits';
            app.ManualEditsSwitch = uiswitch(app.ParametersPanel, 'slider');
            app.ManualEditsSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualEditsSwitchValueChanged, true);
            app.ManualEditsSwitch.Value = 'On';
            app.ResetManualEditsButton = uibutton(app.ParametersPanel, 'push');
            app.ResetManualEditsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetManualEditsButtonPushed, true);
            app.ResetManualEditsButton.Text = 'Reset manual edits';
            app.MinLabel = uilabel(app.ParametersPanel);
            app.MinLabel.Text = 'Min';
            app.ContourMinEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ContourMinEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMinEditFieldValueChanged, true);
            app.MaxLabel = uilabel(app.ParametersPanel);
            app.MaxLabel.Text = 'Max';
            app.ContourMaxEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ContourMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMaxEditFieldValueChanged, true);
            app.UIAxesColorPallet = uiaxes(app.ParametersPanel);
            title(app.UIAxesColorPallet, '')
            xlabel(app.UIAxesColorPallet, 'Contour color')
            ylabel(app.UIAxesColorPallet, '')
            app.UIAxesColorPallet.YTick = [];
            app.UIAxesColorPallet.YTickLabel = '';
            app.ContourlimitsLabel = uilabel(app.ParametersPanel);
            app.ContourlimitsLabel.Text = 'Contour limits';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CurrentComponentTab = uitab(app.TabGroup);
            app.CurrentComponentTab.Title = 'Current Component';
            app.UIAxes_component = uiaxes(app.CurrentComponentTab);
            title(app.UIAxes_component, 'Cell')
            xlabel(app.UIAxes_component, 'X')
            ylabel(app.UIAxes_component, 'Y')
            app.SNRcaimanEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.SNRcaimanEditFieldLabel.Text = 'SNR caiman';
            app.SNRcaimanEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.SNRcaimanEditField.Editable = 'off';
            app.CNNprobEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.CNNprobEditFieldLabel.Text = 'CNN prob';
            app.CNNprobEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.CNNprobEditField.Editable = 'off';
            app.r_valEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.r_valEditFieldLabel.Text = 'r_val';
            app.r_valEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.r_valEditField.Editable = 'off';
            app.SNR2EditFieldLabel = uilabel(app.CurrentComponentTab);
            app.SNR2EditFieldLabel.Text = 'SNR 2';
            app.SNR2EditField = uieditfield(app.CurrentComponentTab, 'text');
            app.SNR2EditField.Editable = 'off';
            app.FiringstabilityEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.FiringstabilityEditFieldLabel.Text = 'Firing stability';
            app.FiringstabilityEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.FiringstabilityEditField.Editable = 'off';
            app.PeaksaveEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.PeaksaveEditFieldLabel.Text = 'Peaks ave';
            app.PeaksaveEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.PeaksaveEditField.Editable = 'off';
            app.StdnoiseEditFieldLabel = uilabel(app.CurrentComponentTab);
            app.StdnoiseEditFieldLabel.Text = 'Std noise';
            app.StdnoiseEditField = uieditfield(app.CurrentComponentTab, 'text');
            app.StdnoiseEditField.Editable = 'off';
            app.EvaluateparamsTab = uitab(app.TabGroup);
            app.EvaluateparamsTab.Title = 'Evaluate params';
            app.CaimanevaluatePanel = uipanel(app.EvaluateparamsTab);
            app.CaimanevaluatePanel.Title = 'Caiman evaluate';
            app.SNRPanel = uipanel(app.CaimanevaluatePanel);
            app.SNRPanel.Title = 'SNR';
            app.SNRthreshSpinnerLabel = uilabel(app.SNRPanel);
            app.SNRthreshSpinnerLabel.Text = {'SNR '; 'thresh'};
            app.SNRthreshSpinner = uispinner(app.SNRPanel);
            app.SNRthreshSpinner.Step = 0.5;
            app.SNRthreshSpinner.Limits = [0 20];
            app.SNRthreshSpinner.Value = 2;
            app.LowestthreshLabel = uilabel(app.SNRPanel);
            app.LowestthreshLabel.Text = {'Lowest'; 'thresh'};
            app.SNRLowestthreshSpinner = uispinner(app.SNRPanel);
            app.SNRLowestthreshSpinner.Step = 0.5;
            app.SNRLowestthreshSpinner.Limits = [0 20];
            app.SNRLowestthreshSpinner.Value = 0.5;
            app.CNNProbThreshPanel = uipanel(app.CaimanevaluatePanel);
            app.CNNProbThreshPanel.Title = 'CNN prob  [0, 1]';
            app.CNNprobthreshLabel = uilabel(app.CNNProbThreshPanel);
            app.CNNprobthreshLabel.Text = {'CNN prob'; 'thresh'};
            app.CNNprobthreshSpinner = uispinner(app.CNNProbThreshPanel);
            app.CNNprobthreshSpinner.Step = 0.05;
            app.CNNprobthreshSpinner.Limits = [0 1];
            app.CNNprobthreshSpinner.Value = 0.99;
            app.LowestthreshSpinner_2Label = uilabel(app.CNNProbThreshPanel);
            app.LowestthreshSpinner_2Label.Text = {'Lowest'; 'thresh'};
            app.CNNLowestthreshSpinner = uispinner(app.CNNProbThreshPanel);
            app.CNNLowestthreshSpinner.Step = 0.05;
            app.CNNLowestthreshSpinner.Limits = [0 1];
            app.CNNLowestthreshSpinner.Value = 0.1;
            app.RvaluesPanel = uipanel(app.CaimanevaluatePanel);
            app.RvaluesPanel.Title = 'R values [0, 1]';
            app.RvalthreshSpinnerLabel = uilabel(app.RvaluesPanel);
            app.RvalthreshSpinnerLabel.Text = {'R val'; 'thresh'};
            app.RvalthreshSpinner = uispinner(app.RvaluesPanel);
            app.RvalthreshSpinner.Step = 0.05;
            app.RvalthreshSpinner.Limits = [0 1];
            app.RvalthreshSpinner.Value = 0.9;
            app.LowestthreshSpinner_3Label = uilabel(app.RvaluesPanel);
            app.LowestthreshSpinner_3Label.Text = {'Lowest'; 'thresh'};
            app.RvalLowestthreshSpinner = uispinner(app.RvaluesPanel);
            app.RvalLowestthreshSpinner.Step = 0.05;
            app.RvalLowestthreshSpinner.Limits = [-1 1];
            app.LampCaimanEval = uilamp(app.CaimanevaluatePanel);
            app.RejectthresholdPanel = uipanel(app.EvaluateparamsTab);
            app.RejectthresholdPanel.Title = 'Reject threshold';
            app.LampRejectThresh = uilamp(app.RejectthresholdPanel);
            app.CheckBoxSNRcaiman = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxSNRcaiman.Text = 'SNR caiman';
            app.CheckBoxSNR2 = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxSNR2.Text = 'SNR2';
            app.CheckBoxSNR2.Value = true;
            app.CheckBoxCNN = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxCNN.Text = 'CNN prob';
            app.CheckBoxRvalues = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxRvalues.Text = 'R values';
            app.CheckBoxMinSigFrac = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxMinSigFrac.Text = 'Min signal frac';
            app.CheckBoxMinSigFrac.Value = true;
            app.EditFieldSNRcaiman = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldSNRcaiman.Limits = [0 Inf];
            app.EditFieldSNRcaiman.Value = 2;
            app.EditFieldSNR2 = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldSNR2.Limits = [0 Inf];
            app.EditFieldSNR2.Value = 2;
            app.EditFieldCNN = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldCNN.Limits = [0 1];
            app.EditFieldCNN.Value = 0.1;
            app.EditFieldRvalues = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldRvalues.Limits = [-1 1];
            app.EditFieldMinSigFrac = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldMinSigFrac.Limits = [0 1];
            app.EditFieldMinSigFrac.Value = 0.5;
            app.CheckBoxFiringStability = uicheckbox(app.RejectthresholdPanel);
            app.CheckBoxFiringStability.Text = 'Firing stability';
            app.EditFieldFiringStability = uieditfield(app.RejectthresholdPanel, 'numeric');
            app.EditFieldFiringStability.Limits = [0 1];
            app.EditFieldFiringStability.Value = 0.01;
            app.EvaluateComponentsButton = uibutton(app.EvaluateparamsTab, 'push');
            app.EvaluateComponentsButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateComponentsButtonPushed, true);
            app.EvaluateComponentsButton.Text = 'Evaluate components';
            app.SwitchCaimanEvaluate = uiswitch(app.EvaluateparamsTab, 'slider');
            app.SwitchCaimanEvaluate.Items = {'Caiman evaluate', 'Reject threshhold'};
            app.SwitchCaimanEvaluate.ValueChangedFcn = createCallbackFcn(app, @SwitchCaimanEvaluateValueChanged, true);
            app.SwitchCaimanEvaluate.Value = 'Reject threshhold';
            app.ParamsDistributionsTab = uitab(app.TabGroup);
            app.ParamsDistributionsTab.Title = 'Params Distributions';
            app.UIAxes_distribution = uiaxes(app.ParamsDistributionsTab);
            title(app.UIAxes_distribution, 'Density')
            xlabel(app.UIAxes_distribution, 'X')
            ylabel(app.UIAxes_distribution, 'Y')
            app.PlotSNRcaimanButton = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotSNRcaimanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSNRcaimanButtonPushed, true);
            app.PlotSNRcaimanButton.Text = 'Plot SNR caiman';
            app.PlotCNNButton = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotCNNButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCNNButtonPushed, true);
            app.PlotCNNButton.Text = 'Plot CNN';
            app.PlotRvalButton = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotRvalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRvalButtonPushed, true);
            app.PlotRvalButton.Text = 'Plot Rval';
            app.PlotSNR2Button = uibutton(app.ParamsDistributionsTab, 'push');
            app.PlotSNR2Button.ButtonPushedFcn = createCallbackFcn(app, @PlotSNR2ButtonPushed, true);
            app.PlotSNR2Button.Text = 'Plot SNR2';
            app.ParamsTab = uitab(app.TabGroup);
            app.ParamsTab.Title = 'Params';
            app.DecayTimeEditFieldLabel = uilabel(app.ParamsTab);
            app.DecayTimeEditFieldLabel.Text = 'Decay Time';
            app.DecayTimeEditField = uieditfield(app.ParamsTab, 'numeric');
            app.DecayTimeEditField.Editable = 'off';
            app.FrameRateEditFieldLabel = uilabel(app.ParamsTab);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ParamsTab, 'numeric');
            app.FrameRateEditField.Editable = 'off';
            app.OnACIDparametersLabel = uilabel(app.ParamsTab);
            app.OnACIDparametersLabel.Text = 'OnACID parameters';
            app.EpochsEditFieldLabel = uilabel(app.ParamsTab);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.ParamsTab, 'numeric');
            app.EpochsEditField.Editable = 'off';
            app.pARmodelEditFieldLabel = uilabel(app.ParamsTab);
            app.pARmodelEditFieldLabel.Text = 'p (AR model)';
            app.pARmodelEditField = uieditfield(app.ParamsTab, 'numeric');
            app.pARmodelEditField.Editable = 'off';
            app.ds_factorEditFieldLabel = uilabel(app.ParamsTab);
            app.ds_factorEditFieldLabel.Text = 'ds_factor';
            app.ds_factorEditField = uieditfield(app.ParamsTab, 'numeric');
            app.ds_factorEditField.Editable = 'off';
            app.DimsEditFieldLabel = uilabel(app.ParamsTab);
            app.DimsEditFieldLabel.Text = 'Dims';
            app.DimsEditField = uieditfield(app.ParamsTab, 'text');
            app.DimsEditField.Editable = 'off';
            app.gSigEditFieldLabel = uilabel(app.ParamsTab);
            app.gSigEditFieldLabel.Text = 'gSig';
            app.gSigEditField = uieditfield(app.ParamsTab, 'text');
            app.gSigEditField.Editable = 'off';
            app.ProcessingparametersLabel = uilabel(app.ParamsTab);
            app.ProcessingparametersLabel.Text = 'Processing parameters';
            app.peakstoaveEditFieldLabel = uilabel(app.ParamsTab);
            app.peakstoaveEditFieldLabel.Text = 'peaks to ave';
            app.peakstoaveEditField = uieditfield(app.ParamsTab, 'text');
            app.peakstoaveEditField.Editable = 'off';
            app.peakbinsizeEditFieldLabel = uilabel(app.ParamsTab);
            app.peakbinsizeEditFieldLabel.Text = 'peak bin size';
            app.peakbinsizeEditField = uieditfield(app.ParamsTab, 'text');
            app.peakbinsizeEditField.Editable = 'off';
            app.TemporaltracePanel = uipanel(app.UIFigure);
            app.TemporaltracePanel.Title = 'Temporal trace';
            app.PlotCSwitchLabel = uilabel(app.TemporaltracePanel);
            app.PlotCSwitchLabel.Text = 'Plot C';
            app.PlotCSwitch = uiswitch(app.TemporaltracePanel, 'slider');
            app.PlotCSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotCSwitchValueChanged, true);
            app.PlotCSwitch.Value = 'On';
            app.PlotRawSwitchLabel = uilabel(app.TemporaltracePanel);
            app.PlotRawSwitchLabel.Text = 'Plot Raw';
            app.PlotRawSwitch = uiswitch(app.TemporaltracePanel, 'slider');
            app.PlotRawSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotRawSwitchValueChanged, true);
            app.PlotSpikesSwitchLabel = uilabel(app.TemporaltracePanel);
            app.PlotSpikesSwitchLabel.Text = 'Plot Spikes';
            app.PlotSpikesSwitch = uiswitch(app.TemporaltracePanel, 'slider');
            app.PlotSpikesSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSpikesSwitchValueChanged, true);
            app.SmoothRawButton = uibutton(app.TemporaltracePanel, 'state');
            app.SmoothRawButton.ValueChangedFcn = createCallbackFcn(app, @SmoothRawButtonValueChanged, true);
            app.SmoothRawButton.Text = {'Smooth'; 'raw'};
            app.WindowSpinnerLabel = uilabel(app.TemporaltracePanel);
            app.WindowSpinnerLabel.Text = 'Window';
            app.SmoothRawWindowSpinner = uispinner(app.TemporaltracePanel);
            app.SmoothRawWindowSpinner.Limits = [1 200];
            app.SmoothRawWindowSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothRawWindowSpinnerValueChanged, true);
            app.SmoothRawWindowSpinner.Value = 10;
            app.PlotLastCSwitchLabel = uilabel(app.TemporaltracePanel);
            app.PlotLastCSwitchLabel.Text = 'Plot Last C';
            app.PlotLastCSwitch = uiswitch(app.TemporaltracePanel, 'slider');
            app.PlotLastCSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotLastCSwitchValueChanged, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SpatialcomponentsTab = uitab(app.TabGroup2);
            app.SpatialcomponentsTab.Title = 'Spatial components';
            app.UIAxes_Accepted = uiaxes(app.SpatialcomponentsTab);
            title(app.UIAxes_Accepted, 'Accepted')
            xlabel(app.UIAxes_Accepted, '')
            ylabel(app.UIAxes_Accepted, '')
            app.UIAxes_Rejected = uiaxes(app.SpatialcomponentsTab);
            title(app.UIAxes_Rejected, 'Rejected')
            xlabel(app.UIAxes_Rejected, '')
            ylabel(app.UIAxes_Rejected, '')
            app.DeconvolutionTab = uitab(app.TabGroup2);
            app.DeconvolutionTab.Title = 'Deconvolution';
            app.DeonvolutionTypeGroup = uitabgroup(app.DeconvolutionTab);
            app.smoothdfdtTab = uitab(app.DeonvolutionTypeGroup);
            app.smoothdfdtTab.Title = 'smooth df/dt';
            app.NormalizeCheckBox = uicheckbox(app.smoothdfdtTab);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.ScaleEditFieldLabel = uilabel(app.smoothdfdtTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.smoothdfdtTab, 'numeric');
            app.ScaleEditField.Limits = [0 Inf];
            app.ScaleEditField.Value = 1;
            app.RectifyCheckBox = uicheckbox(app.smoothdfdtTab);
            app.RectifyCheckBox.Text = 'Rectify';
            app.RectifyCheckBox.Value = true;
            app.SmoothingwindowmsEditFieldLabel = uilabel(app.smoothdfdtTab);
            app.SmoothingwindowmsEditFieldLabel.Text = 'Smoothing window (ms)';
            app.SmoothingwindowmsEditField = uieditfield(app.smoothdfdtTab, 'numeric');
            app.SmoothingwindowmsEditField.Limits = [1 Inf];
            app.SmoothingwindowmsEditField.Value = 500;
            app.PlotthresholdCheckBox = uicheckbox(app.smoothdfdtTab);
            app.PlotthresholdCheckBox.Text = 'Plot threshold';
            app.PlotthresholdCheckBox.Value = true;
            app.ThresholdmagZEditFieldLabel = uilabel(app.smoothdfdtTab);
            app.ThresholdmagZEditFieldLabel.Text = 'Threshold mag (Z)';
            app.ThresholdmagZEditField = uieditfield(app.smoothdfdtTab, 'numeric');
            app.ThresholdmagZEditField.Limits = [0 Inf];
            app.ThresholdmagZEditField.Value = 2;
            app.ApplythresholdCheckBox = uicheckbox(app.smoothdfdtTab);
            app.ApplythresholdCheckBox.Text = 'Apply threshold';
            app.ShiftEditFieldLabel = uilabel(app.smoothdfdtTab);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.smoothdfdtTab, 'numeric');
            app.PlottingparamsLabel = uilabel(app.smoothdfdtTab);
            app.PlottingparamsLabel.Text = 'Plotting params';
            app.SignalextractionparamsLabel = uilabel(app.smoothdfdtTab);
            app.SignalextractionparamsLabel.Text = 'Signal extraction params';
            app.PlotsmoothdfofSwitchLabel = uilabel(app.DeconvolutionTab);
            app.PlotsmoothdfofSwitchLabel.Text = 'Plot smooth dfof';
            app.PlotsmoothdfofSwitch = uiswitch(app.DeconvolutionTab, 'slider');
            app.PlotsmoothdfofSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotsmoothdfofSwitchValueChanged, true);
            app.UpdateplotsButton = uibutton(app.DeconvolutionTab, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.CellEditFieldLabel = uilabel(app.DeconvolutionTab);
            app.CellEditFieldLabel.Text = 'Cell';
            app.CellEditField = uieditfield(app.DeconvolutionTab, 'numeric');
            app.CellEditField.Editable = 'off';
            app.CellEditField.Value = 1;
            app.Estimatedtimeconstantar1Label = uilabel(app.DeconvolutionTab);
            app.Estimatedtimeconstantar1Label.Text = 'Estimated time constant ar1';
            app.TaudecayEditFieldLabel = uilabel(app.DeconvolutionTab);
            app.TaudecayEditFieldLabel.Text = 'Tau decay';
            app.TaudecayEditFieldAR1 = uieditfield(app.DeconvolutionTab, 'numeric');
            app.TaudecayEditFieldAR1.Editable = 'off';
            app.TaudecayEditField_3Label = uilabel(app.DeconvolutionTab);
            app.TaudecayEditField_3Label.Text = 'Tau decay';
            app.TaudecayEditFieldAR2 = uieditfield(app.DeconvolutionTab, 'numeric');
            app.TaudecayEditFieldAR2.Editable = 'off';
            app.TauriseEditField_3Label = uilabel(app.DeconvolutionTab);
            app.TauriseEditField_3Label.Text = 'Tau rise';
            app.TauriseEditFieldAR2 = uieditfield(app.DeconvolutionTab, 'numeric');
            app.TauriseEditFieldAR2.Editable = 'off';
            app.Estimatedtimeconstantsar2Label_2 = uilabel(app.DeconvolutionTab);
            app.Estimatedtimeconstantsar2Label_2.Text = 'Estimated time constants ar2';
            app.DataframeratefpsEditFieldLabel = uilabel(app.DeconvolutionTab);
            app.DataframeratefpsEditFieldLabel.Text = 'Data frame rate (fps)';
            app.DataframeratefpsEditField = uieditfield(app.DeconvolutionTab, 'numeric');
            app.DataframeratefpsEditField.Editable = 'off';
            app.RunallcellsButton_2 = uibutton(app.DeconvolutionTab, 'push');
            app.RunallcellsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunallcellsButtonPushed, true);
            app.RunallcellsButton_2.Text = 'Run all cells';
            app.CellnumberSpinnerLabel = uilabel(app.UIFigure);
            app.CellnumberSpinnerLabel.Text = 'Cell number';
            app.CellnumberSpinner = uispinner(app.UIFigure);
            app.CellnumberSpinner.Limits = [1 Inf];
            app.CellnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @CellnumberSpinnerValueChanged, true);
            app.CellnumberSpinner.Value = 1;
            app.PressupdownkeytochangecellnumButton = uibutton(app.UIFigure, 'state');
            app.PressupdownkeytochangecellnumButton.Text = 'Press up/down key to change cell num';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = caiman_sorter_light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end